classdef templatemanager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MyosortTab                      matlab.ui.container.Tab
        VetBatchDurationLabel           matlab.ui.control.Label
        ClusterTemplateDurationLabel    matlab.ui.control.Label
        AlignWaveformDurationLabel      matlab.ui.control.Label
        FilterHighCutLabel              matlab.ui.control.Label
        FilterLowCutLabel               matlab.ui.control.Label
        SummaryTextArea                 matlab.ui.control.TextArea
        SampleRateLabel                 matlab.ui.control.Label
        TemplateMatchingLabel           matlab.ui.control.Label
        NoiseCovarianceLabel            matlab.ui.control.Label
        VetLabel                        matlab.ui.control.Label
        MergeLabel                      matlab.ui.control.Label
        TriageLabel                     matlab.ui.control.Label
        ClusterLabel                    matlab.ui.control.Label
        AlignLabel                      matlab.ui.control.Label
        DetectLabel                     matlab.ui.control.Label
        FilterLabel                     matlab.ui.control.Label
        RunPushButton                   matlab.ui.control.Button
        ExportPushButton                matlab.ui.control.Button
        ImportPushButton                matlab.ui.control.Button
        BrowsePushButton                matlab.ui.control.Button
        TemplateMatchingCheckBox        matlab.ui.control.CheckBox
        VetCheckBox                     matlab.ui.control.CheckBox
        NoiseCovarianceCheckBox         matlab.ui.control.CheckBox
        MergeCheckBox                   matlab.ui.control.CheckBox
        TriageCheckBox                  matlab.ui.control.CheckBox
        ClusterCheckBox                 matlab.ui.control.CheckBox
        AlignCheckBox                   matlab.ui.control.CheckBox
        DetectCheckBox                  matlab.ui.control.CheckBox
        FilterCheckBox                  matlab.ui.control.CheckBox
        AlphaEditFieldLabel             matlab.ui.control.Label
        NoiseCovarianceAlphaEditField   matlab.ui.control.NumericEditField
        BinsEditFieldLabel              matlab.ui.control.Label
        TriageBinsEditField             matlab.ui.control.NumericEditField
        RoundsEditFieldLabel_2          matlab.ui.control.Label
        VetRoundsEditField              matlab.ui.control.NumericEditField
        CVThresholdEditFieldLabel       matlab.ui.control.Label
        VetCVThresholdEditField         matlab.ui.control.NumericEditField
        MinCountEditFieldLabel          matlab.ui.control.Label
        TriageMinCountEditField         matlab.ui.control.NumericEditField
        RoundsEditFieldLabel            matlab.ui.control.Label
        ClusterRoundsEditField          matlab.ui.control.NumericEditField
        FeaturesEditFieldLabel          matlab.ui.control.Label
        ClusterFeaturesEditField        matlab.ui.control.NumericEditField
        BatchSizeEditFieldLabel         matlab.ui.control.Label
        AlignBatchSizeEditField         matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        DetectThresholdEditField        matlab.ui.control.NumericEditField
        RefractoryDurationLabel         matlab.ui.control.Label
        TemplateMatchingRefractoryDurationEditField  matlab.ui.control.NumericEditField
        BatchDurationEditFieldLabel     matlab.ui.control.Label
        VetBatchDurationEditField       matlab.ui.control.NumericEditField
        ResidualThresholdEditFieldLabel  matlab.ui.control.Label
        VetResidualThresholdEditField   matlab.ui.control.NumericEditField
        MisalignThresholdEditFieldLabel  matlab.ui.control.Label
        VetMisalignThresholdEditField   matlab.ui.control.NumericEditField
        ThresholdLabel                  matlab.ui.control.Label
        MergeThresholdEditField         matlab.ui.control.NumericEditField
        CutProportionEditFieldLabel     matlab.ui.control.Label
        TriageCutProportionEditField    matlab.ui.control.NumericEditField
        TemplateDurationEditFieldLabel  matlab.ui.control.Label
        ClusterTemplateDurationEditField  matlab.ui.control.NumericEditField
        WaveformDurationEditFieldLabel  matlab.ui.control.Label
        AlignWaveformDurationEditField  matlab.ui.control.NumericEditField
        HighCutEditFieldLabel           matlab.ui.control.Label
        FilterHighCutEditField          matlab.ui.control.NumericEditField
        LowCutEditFieldLabel            matlab.ui.control.Label
        FilterLowCutEditField           matlab.ui.control.NumericEditField
        OrderEditFieldLabel             matlab.ui.control.Label
        FilterOrderEditField            matlab.ui.control.NumericEditField
        MaxArrayEditFieldLabel          matlab.ui.control.Label
        MaxArrayEditField               matlab.ui.control.NumericEditField
        SampleRateEditFieldLabel        matlab.ui.control.Label
        SampleRateEditField             matlab.ui.control.NumericEditField
        SavePathEditFieldLabel          matlab.ui.control.Label
        SavePathEditField               matlab.ui.control.EditField
        TimeseriesTab                   matlab.ui.container.Tab
        TimeseriesAxes                  matlab.ui.control.UIAxes
        TimeseriesSlider                matlab.ui.control.Slider
        TimeseriesChannelsListBox       matlab.ui.control.ListBox
        FrameLabel                      matlab.ui.control.Label
        TimeseriesFrameEditField        matlab.ui.control.NumericEditField
        TimeseriesFrameLabel            matlab.ui.control.Label
        RandomFramePushButton           matlab.ui.control.Button
        OverlayLabel                    matlab.ui.control.Label
        OverlayListBox                  matlab.ui.control.ListBox
        TimeseriesBotmPushButton        matlab.ui.control.Button
        PositionLabel                   matlab.ui.control.Label
        TimeseriesPositionEditField     matlab.ui.control.NumericEditField
        TimeseriesPositionLabel         matlab.ui.control.Label
        TimeseriesAxesUnitsSwitch       matlab.ui.control.Switch
        TemplatesTab                    matlab.ui.container.Tab
        ActiveTemplatesAxes             matlab.ui.control.UIAxes
        InactiveTemplatesAxes           matlab.ui.control.UIAxes
        ActiveListBoxLabel              matlab.ui.control.Label
        ActiveTemplatesListBox          matlab.ui.control.ListBox
        InactiveListBoxLabel            matlab.ui.control.Label
        InactiveTemplatesListBox        matlab.ui.control.ListBox
        InactivateTemplatePushButton    matlab.ui.control.Button
        ActivateTemplatePushButton      matlab.ui.control.Button
        PlotSelectedActiveCheckBox      matlab.ui.control.CheckBox
        TemplatesBotmPushButton         matlab.ui.control.Button
        BatchDurationEditFieldLabel_2   matlab.ui.control.Label
        TemplatesBotmBatchDurationEditField  matlab.ui.control.NumericEditField
        TemplatesBotmBatchDurationLabel  matlab.ui.control.Label
        BatchesLabel                    matlab.ui.control.Label
        TemplatesBotmBatchesEditField   matlab.ui.control.NumericEditField
        ResetTemplatesPushButton        matlab.ui.control.Button
        PlotSelectedLabel               matlab.ui.control.Label
        PlotSelectedInactiveCheckBox    matlab.ui.control.CheckBox
        ShiftActiveTemplateEditField    matlab.ui.control.NumericEditField
        msLabel                         matlab.ui.control.Label
        ShiftActiveTemplatePushButton   matlab.ui.control.Button
        TemplatesSourceDropDown         matlab.ui.control.DropDown
        DeleteTemplatePushButton        matlab.ui.control.Button
        ToggleGoodTemplatePushButton    matlab.ui.control.Button
        WaveformsTab                    matlab.ui.container.Tab
        WaveformAxes                    matlab.ui.control.UIAxes
        FeaturesAxes                    matlab.ui.control.UIAxes
        WaveformUnitsListBox            matlab.ui.control.ListBox
        WaveformPlotStyleSwitch         matlab.ui.control.Switch
        MaxLinesLabel                   matlab.ui.control.Label
        WaveformMaxLinesEditField       matlab.ui.control.NumericEditField
        ResampleWaveformsPushButton     matlab.ui.control.Button
        CutProportionLabel              matlab.ui.control.Label
        WaveformsCutProportionEditField  matlab.ui.control.NumericEditField
        WaveformChannelsListBox         matlab.ui.control.ListBox
        RemoveOutliersPushButton        matlab.ui.control.Button
        Feat1Label                      matlab.ui.control.Label
        Feature1DropDown                matlab.ui.control.DropDown
        Feat2Label                      matlab.ui.control.Label
        Feature2DropDown                matlab.ui.control.DropDown
        Feat3Label                      matlab.ui.control.Label
        Feature3DropDown                matlab.ui.control.DropDown
        FeaturePolygonCheckBox          matlab.ui.control.CheckBox
        SplitWaveformsPushButton        matlab.ui.control.Button
        OverlayWaveformsCheckBox        matlab.ui.control.CheckBox
        MergeWaveformsPushButton        matlab.ui.control.Button
        ClearPolygonPushButton          matlab.ui.control.Button
        SetPolygonToggleButton          matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        Defaults    % app defaults
        X           % timeseries data
        t           % time vector
        noiseStd    % noise standard deviation
        Cinv        % inverse noise covariance
        spikes      % spike indices
        waveforms   % waveform shapes
        Label       % waveform labels
        Templates   % waveform templates
        botmTestIdx % indices to use for testing template matching
    end
    
    methods (Access = private)
        % -----------------------------------------------------------------
        % READ DEFAULT APP COMPONENT VALUES
        % -----------------------------------------------------------------
        function read_defaults(app)
            fn = fieldnames(app);
            editable = cellfun(@(s) contains(s,{'EditField','ListBox','CheckBox','Switch'}) && ~contains(s,'Label') && ~contains(s,'SavePath'), fn);
            editFn = fn(editable);
            app.Defaults = cell2struct(cell(size(editFn)),editFn);
            for ii = 1:length(editFn)
                if contains(editFn{ii},'ListBox')
                    app.Defaults.(editFn{ii}).Items = app.(editFn{ii}).Items;
                end
                app.Defaults.(editFn{ii}).Value = app.(editFn{ii}).Value;
            end
        end
        
        % -----------------------------------------------------------------
        % WRITE DEFAULT APP COMPONENT VALUES
        % -----------------------------------------------------------------
        function write_defaults(app)
            % copy default component values
            fn = fieldnames(app.Defaults);
            for ii = 1:length(fn)
                if contains(fn{ii},'ListBox')
                    app.(fn{ii}).Items = app.Defaults.(fn{ii}).Items;
                end
                app.(fn{ii}).Value = app.Defaults.(fn{ii}).Value;
            end
        end
        
        % -----------------------------------------------------------------
        % READ SORT OPTIONS FROM FILE
        % -----------------------------------------------------------------
        function read_sort_options(app)
            load([app.SavePathEditField.Value 'settings'],'opt')
            % sample rate and time
            app.SampleRateEditField.Value = opt.Fs;
            app.t = (0:size(app.X,2)-1)/opt.Fs;
            % max array size
            app.MaxArrayEditField.Value = opt.maxArray;
            % filter settings
            app.FilterOrderEditField.Value = 2;
            app.FilterLowCutEditField.Value = 500;
            app.FilterHighCutEditField.Value = Inf;
            % detect
            app.DetectThresholdEditField.Value = opt.detectThreshold;
            % align
            app.AlignWaveformDurationEditField.Value = opt.waveformDuration(1);
            app.AlignBatchSizeEditField.Value = opt.alignBatchSize;
            % cluster
            app.ClusterTemplateDurationEditField.Value = opt.waveformDuration(2);
            app.ClusterFeaturesEditField.Value = opt.clusterFeatures;
            app.ClusterRoundsEditField.Value = opt.clusterRounds;
            % triage
            app.TriageCutProportionEditField.Value = opt.triageCutProportion;
            app.TriageBinsEditField.Value = opt.triageBins;
            app.TriageMinCountEditField.Value = opt.triageMinCount;
            % merge
            app.MergeThresholdEditField.Value = max(opt.mergeThreshold);
            % noise covariance
            app.NoiseCovarianceAlphaEditField.Value = opt.noiseAlpha;
            % vet
            app.VetCVThresholdEditField.Value = opt.vetCVThreshold;
            app.VetMisalignThresholdEditField.Value = opt.vetMisalignThreshold;
            app.VetResidualThresholdEditField.Value = opt.vetResidualThreshold;
            app.VetBatchDurationEditField.Value = opt.vetBatchDuration;
            app.VetRoundsEditField.Value = opt.vetRounds;
            % template matching
            %             app.TemplateMatchingRefractoryDurationEditField.Value = opt.maxArray;
        end
        
        % -----------------------------------------------------------------
        % RESET GUI COMPONENTS
        % -----------------------------------------------------------------
        function reset_gui(app)
            % update channel lists
            chanList = cellfun(@(n) sprintf('Channel %i',n), num2cell(1:size(app.X,1))', 'uni',false);
            app.TimeseriesChannelsListBox.Items = chanList;
            app.TimeseriesChannelsListBox.Value = chanList;
            app.WaveformChannelsListBox.Items = chanList;
            app.WaveformChannelsListBox.Value = chanList;
            % update time series slider
            app.TimeseriesSlider.Limits = [0 app.t(end)-app.TimeseriesFrameEditField.Value];
            app.TimeseriesSlider.MajorTicks = floor(linspace(0,app.TimeseriesSlider.Limits(2),10));
            app.TimeseriesSlider.MinorTicks = floor(linspace(0,app.TimeseriesSlider.Limits(2),50));
            app.TimeseriesSlider.MajorTickLabels = cellfun(@num2str,num2cell(app.TimeseriesSlider.MajorTicks),'uni',false);
            % update source list
            load([app.SavePathEditField.Value 'templates'],'W')
            fn = fieldnames(W);
            fn = fn(structfun(@(x) ~any(isnan(x(:))) && ~isempty(x),W));
            app.TemplatesSourceDropDown.Items = fn;
            app.TemplatesSourceDropDown.Value = fn{end};
            % update feature list
            featureList = [{'Time'; 'Energy'}; cellfun(@(n) sprintf('PC%i',n),num2cell(1:10)','uni',false)];
            app.Feature1DropDown.Items = featureList;
            app.Feature2DropDown.Items = [{'None'}; featureList];
            app.Feature3DropDown.Items = [{'None'}; featureList];
            app.Feature1DropDown.Value = app.Feature1DropDown.Items{1};
            app.Feature2DropDown.Value = app.Feature2DropDown.Items{3};
            app.Feature3DropDown.Value = app.Feature3DropDown.Items{1};
            app.FeaturesAxes.UserData.poly = [];
        end
        
        % -----------------------------------------------------------------
        % INITIALIZE SPIKES, LABELS, AND TEMPLATES
        % -----------------------------------------------------------------
        function init_spike_data(app)
            pth = app.SavePathEditField.Value;
            load([pth 'spikes'],'Spk')
            load([pth 'labels'],'Lab')
            load([pth 'templates'],'W')
            
            % import source
            src = app.TemplatesSourceDropDown.Value;
            
            % import spikes
            spkSrc = src;
            if all(isnan(Spk.(spkSrc)))
                spkFn = fieldnames(Spk);
                validField = structfun(@(x) ~any(isnan(x)),Spk);
                preceedingField = ~ismember(1:length(spkFn),find(ismember(spkFn,src)):length(spkFn))';
                spkSrc = spkFn{find(validField & preceedingField,1,'last')};
            end
            app.spikes = double(Spk.(spkSrc));
            
            % label import source
            labSrc = src;
            if all(isnan(Lab.(labSrc)))
                labFn = fieldnames(Lab);
                validField = structfun(@(x) ~any(isnan(x)),Lab);
                preceedingField = ~ismember(1:length(labFn),find(ismember(labFn,src)):length(labFn))';
                labSrc = labFn{find(validField & preceedingField,1,'last')};
            end
            
            % draw waveforms
            app.waveforms = myo.spktrig(app.X, app.spikes, size(W.(src),1));
            
            % set labels and templates
            [app.Label, app.Templates] = deal(struct('active',{[]},'inactive',{[]}));
            
            uql = setdiff(unique(Lab.(labSrc)),0);
            app.Label.active = false(length(uql),length(app.spikes));
            for ii = 1:length(uql)
                app.Label.active(ii,:) = (Lab.(labSrc)==uql(ii));
            end
            app.Label.inactive = (Lab.(labSrc)==0);
            
            app.Templates.active = W.(src);
            app.Templates.inactive = myo.wavetemplate(app.waveforms, app.Label.inactive, false);
            
            % initialize "good" unit list
            app.ActiveTemplatesListBox.UserData.goodUnit = false(size(app.Templates.active,3),1);
            
            % initialize units listboxes
            update_units_listboxes(app);
        end
        
        % -----------------------------------------------------------------
        % UPDATE TIMESERIES AXES
        % -----------------------------------------------------------------
        function redraw_timeseries(app)
            
            if strcmp(app.TimeseriesAxesUnitsSwitch.Value,'sigma')
                normFactor = app.noiseStd;
            else
                normFactor = ones(size(app.noiseStd));
            end
            normFactor = normFactor(:);
            
            tIdx = find(app.t >= app.TimeseriesPositionEditField.Value,1) ...
                + app.TimeseriesFrameEditField.UserData.Properties.tIdx;
            
            chan = ismember(app.TimeseriesChannelsListBox.Items,app.TimeseriesChannelsListBox.Value);
            nChan = nnz(chan);
            Y = double(app.X(chan,tIdx))./normFactor;
            
            BUFF = .05;
            TICK_POS = [1/3 2/3];
            
            yLim = [min(Y,[],2), max(Y,[],2)]';
            yAbsMax = max(abs(yLim),[],1);
            yPos = flipud([0;cumsum((1+BUFF)*2*ones(nChan-1,1))]);
            yTickPos = [yPos-fliplr(TICK_POS), yPos, yPos+TICK_POS];
            yTick = round((yTickPos-yPos).*yAbsMax(:));
            
            yTickPos = reshape(fliplr(yTickPos)',(1+2*length(TICK_POS))*nChan,1);
            yTick = reshape(fliplr(yTick)',(1+2*length(TICK_POS))*nChan,1);
            
            for ii = 1:nChan
                app.TimeseriesAxes.Children(ii).XData = app.t(tIdx);
                app.TimeseriesAxes.Children(ii).YData = Y(ii,:)/yAbsMax(ii) + yPos(ii);
            end
            
            app.TimeseriesAxes.XLim = app.t(tIdx([1 end]));
            app.TimeseriesAxes.YLim = (1+BUFF)*[-1;1] + flipud(yPos([1 end]));
            
            app.TimeseriesAxes.YTick = flipud(yTickPos);
            app.TimeseriesAxes.YTickLabel = cellfun(@num2str,flipud(num2cell(yTick)),'uni',false);
        end
        
        % -----------------------------------------------------------------
        % UNIT COLOR MAP
        % -----------------------------------------------------------------
        function cmap = unit_colormap(~,N)
            cmap = brewermap(N,'Spectral');
            cmap = max([0 0 0],cmap-0.2);
        end
        
        % -----------------------------------------------------------------
        % UPDATE UNITS LISTBOXES
        % -----------------------------------------------------------------
        function update_units_listboxes(app)
            % update active
            activeUnits = cellfun(@(n) sprintf('Unit %i',n), num2cell(1:size(app.Label.active,1))', 'uni', false);
            goodUnits = app.ActiveTemplatesListBox.UserData.goodUnit;
            activeUnits(goodUnits) = cellfun(@(x) [x ' *'],activeUnits(goodUnits),'uni',false);
            app.ActiveTemplatesListBox.Items = activeUnits;
            app.WaveformUnitsListBox.Items = activeUnits;
            % update inactive
            app.InactiveTemplatesListBox.Items = cellfun(@(n) sprintf('Unit %i',n), num2cell(1:size(app.Label.inactive,1))', 'uni', false);
        end
        
        % -----------------------------------------------------------------
        % UPDATE TEMPLATES AXES
        % -----------------------------------------------------------------
        function redraw_templates(app,fn)
            if strcmp(app.TimeseriesAxesUnitsSwitch.Value,'sigma')
                normFactor = app.noiseStd;
            else
                normFactor = ones(size(app.noiseStd));
            end
            normFactor = normFactor(:)';
            for ii = 1:length(fn)
                fnUp = [upper(fn{ii}(1)), fn{ii}(2:end)];
                
                N = size(app.Label.(fn{ii}),1);
                cmap = unit_colormap(app,N);
                unitNo = 1:N;
                
                % plot mask
                if app.(['PlotSelected' fnUp 'CheckBox']).Value
                    mask = ismember(app.([fnUp 'TemplatesListBox']).Items,app.([fnUp 'TemplatesListBox']).Value);
                else
                    mask = true(N,1);
                end
                cla(app.([fnUp 'TemplatesAxes']))
                if any(mask) && ~isempty(app.Templates.(fn{ii}))
                    plotwavetemplate(app.Templates.(fn{ii})(:,:,mask)./normFactor,...
                        'axes',app.([fnUp 'TemplatesAxes']),...
                        'cmap',cmap(mask,:),...
                        'xTickLabel',cellfun(@num2str,num2cell(unitNo(mask)),'uni',false));
                end
            end
        end
        
        % -----------------------------------------------------------------
        % UPDATE WAVEFORMS AXES
        % -----------------------------------------------------------------
        function redraw_waveforms(app)
            
            cla(app.WaveformAxes,'reset')
            
            % fetch waveforms
            Fs = app.SampleRateEditField.Value;
            waveLen = size(app.Templates.active,1);
            waveDur = waveLen/Fs;
            chan = ismember(app.WaveformChannelsListBox.Items, app.WaveformChannelsListBox.Value);
            unitNo = find(ismember(app.WaveformUnitsListBox.Items, app.WaveformUnitsListBox.Value));
            nChan = nnz(chan);
            nUnit = length(unitNo);
            if nChan == 0 || nUnit == 0
                return
            end
            waves = cell(nChan,nUnit);
            nSpk = zeros(1,nUnit);
            for ii = 1:nUnit
                
                ws = app.waveforms(chan,:,app.Label.active(unitNo(ii),:));
                
                % downsample
                if strcmp(app.WaveformPlotStyleSwitch.Value, 'Line')
                    ws = datasample(ws,min(size(ws,3),app.WaveformMaxLinesEditField.Value),3,'Replace',false);
                end
                nSpk(ii) = size(ws,3);
                
                % normalize
                if strcmp(app.TimeseriesAxesUnitsSwitch.Value, 'sigma')
                    ws = ws./app.noiseStd(chan);
                end
                waves(:,ii) = cellfun(@(x) squeeze(x)',mat2cell(ws,ones(size(ws,1),1),size(ws,2),size(ws,3)),'uni',false);
            end
            
            % normalize
            absMax = max(cellfun(@(x) max(max(abs(x),[],2),[],1), waves),[],2);
            waves = cellfun(@(x,m) x/m, waves, repmat(num2cell(absMax),1,nUnit), 'uni',false);
            
            % apply vertical offset to each group
            BUFF = .05;
            baseline = flipud([0;cumsum((1+BUFF)*2*ones(nChan-1,1))]);
            waves = cellfun(@(x,b) x+b, waves, repmat(num2cell(baseline),1,nUnit), 'uni',false);
            
            % vertical tick marks and labels
            TICK_POS = [1/3 2/3];
            yTickPos = [baseline-fliplr(TICK_POS), baseline, baseline+TICK_POS];
            yTick = round((yTickPos-baseline).*absMax(:));
            yTickPos = reshape(fliplr(yTickPos)',(1+2*length(TICK_POS))*nChan,1);
            yTick = reshape(fliplr(yTick)',(1+2*length(TICK_POS))*nChan,1);
            
            % horizontal tick marks and labels
            xPad = round(BUFF*waveLen);
            xTick = repmat((1:waveDur*1e3),nUnit,1);
            xTickPos = round((Fs/1e3)*xTick);
            xTickPos = xTickPos + cumsum([0;repmat(waveLen+xPad,nUnit-1,1)]);
            xTickPos = reshape(xTickPos',1,numel(xTickPos));
            xTick = reshape(xTick',1,numel(xTick));
            
            % split view if polygon masking
            if any(app.FeaturesAxes.UserData.mask)
                nUnit = 2;
                waves = [waves, waves];
                waves(:,1) = cellfun(@(x) x(app.FeaturesAxes.UserData.mask,:), waves(:,1), 'uni',false);
                waves(:,2) = cellfun(@(x) x(~app.FeaturesAxes.UserData.mask,:), waves(:,2), 'uni',false);
                nSpk = [nnz(app.FeaturesAxes.UserData.mask), nnz(~app.FeaturesAxes.UserData.mask)];
            end
            
            if strcmp(app.WaveformPlotStyleSwitch.Value, 'Hist')
                
                % reformat
                waves = mat2cell(waves, nChan, ones(1,nUnit));
                waves = cellfun(@(x) cat(1,x{:}), waves, 'uni',false);
                
                % bins
                bins = linspace(-1-BUFF,baseline(1)+1+BUFF,1e3*(2+range(baseline))/4);
                
                counts = cell(1,nUnit);
                for ii = 1:nUnit
                    
                    % bin waveform amplitudes at each time step
                    counts{ii} = cell(1,waveLen);
                    for tt = 1:waveLen
                        counts{ii}{tt} = histc(waves{ii}(:,tt),bins);
                    end
                    
                    % filter
                    counts{ii} = cell2mat(cellfun(@(x) smooth1D(x,1,'gau','sd',1), counts{ii}, 'uni',false));
                    
                    % log transform
                    epsilon = 10^ceil(log10(abs(min(counts{ii}(:)))));
                    counts{ii} = log(counts{ii}/100 + epsilon);
                    
                    % standardize
                    % counts{ii} = counts{ii} - min(counts{ii}(:));
                    % counts{ii} = counts{ii}/max(counts{ii}(:));
                end
                
                % augment with x-buffer
                minCount = min(cellfun(@(x) min(x(:)),counts));
                xFill = repmat({minCount*ones(length(bins),xPad)},1,nUnit);
                counts = reshape([counts;xFill],1,2*nUnit);
                counts = cell2mat(counts(1:end-1));
                
                % plot
                imagesc(app.WaveformAxes,counts)
                app.WaveformAxes.YDir = 'normal';
                
                % truncate color axis to emphasize noise
                cax = caxis(app.WaveformAxes);
                caxis(app.WaveformAxes,[cax(1)/4, cax(2)])
                
                % set axes limits
                app.WaveformAxes.XLim = [1 size(counts,2)];
                app.WaveformAxes.YLim = [1 length(bins)];
                
                % set axes ticks
                app.WaveformAxes.XTick = xTickPos;
                app.WaveformAxes.XTickLabel = cellfun(@num2str,num2cell(xTick),'uni',false);
                app.WaveformAxes.YTick = flipud(round(length(bins)*(1+yTickPos)/(2+range(baseline))));
                app.WaveformAxes.YTickLabel = cellfun(@num2str,num2cell(flipud(yTick)),'uni',false);
                
                % set axes labels
                app.WaveformAxes.XLabel.String = 'ms';
                app.WaveformAxes.YLabel.String = app.TimeseriesAxesUnitsSwitch.Value;
                
                % colormap
                colormap(app.WaveformAxes,'bone')
                
                % spike counts
                for ii = 1:nUnit
                    text(app.WaveformAxes,0.05*waveLen+(ii-1)*(xPad+waveLen),0.025*length(bins),['n = ' num2str(nSpk(ii))],'color','w','FontSize',15)
                end
                
            else % plot lines
                
                hold(app.WaveformAxes,'on')
                
                % colormap
                if any(app.FeaturesAxes.UserData.mask)
                    cmap = [0 0 1; 0 0 0];
                else
                    if nUnit == 1
                        cmap = [0 0 0];
                    else
                        cmap = unit_colormap(app, size(app.Label.active,1));
                        cmap = cmap(unitNo,:);
                    end
                end
                
                for ii = 1:nUnit
                    for jj = 1:nChan
                        plot(app.WaveformAxes, (1:waveLen)+(xPad+waveLen)*(ii-1), waves{jj,ii}, 'color', cmap(ii,:))
                    end
                end
                
                % set axes limits
                app.WaveformAxes.XLim = [1 nUnit*(waveLen+xPad)-xPad];
                app.WaveformAxes.YLim = (1+BUFF)*[-1;1]+[baseline(end);baseline(1)];
                
                % set axes ticks
                app.WaveformAxes.XTick = xTickPos;
                app.WaveformAxes.XTickLabel = cellfun(@num2str,num2cell(xTick),'uni',false);
                app.WaveformAxes.YTick = flipud(yTickPos);
                app.WaveformAxes.YTickLabel = cellfun(@num2str,num2cell(flipud(yTick)),'uni',false);
                
                % set axes labels
                app.WaveformAxes.XLabel.String = 'ms';
                app.WaveformAxes.YLabel.String = app.TimeseriesAxesUnitsSwitch.Value;
            end
        end
        
        % -----------------------------------------------------------------
        % UPDATE WAVEFORM FEATURES
        % -----------------------------------------------------------------
        function update_features(app)
            
            cla(app.FeaturesAxes,'reset')
            app.FeaturesAxes.UserData.poly = [];
            
            % fetch waveforms
            Fs = app.SampleRateEditField.Value;
            chan = ismember(app.WaveformChannelsListBox.Items, app.WaveformChannelsListBox.Value);
            unitNo = find(ismember(app.WaveformUnitsListBox.Items, app.WaveformUnitsListBox.Value));
            nChan = nnz(chan);
            nUnit = length(unitNo);
            if nChan == 0 || nUnit == 0
                return
            end
            [spkIdx,label,waves] = deal(cell(1,nUnit));
            nSpk = zeros(1,nUnit);
            for ii = 1:nUnit
                spkIdx{ii} = app.spikes(app.Label.active(unitNo(ii),:));
                nSpk(ii) = length(spkIdx{ii});
                waves{ii} = myo.flatten(app.waveforms(chan,:,app.Label.active(unitNo(ii),:)));
                label{ii} = unitNo(ii)*ones(1,nSpk(ii));
            end
            spkIdx = cell2mat(spkIdx);
            waves = cell2mat(waves);
            label = cell2mat(label);
            
            % waveform energy
            ener = sqrt(sum(waves.^2,1));
            
            % project into feature space
            [~,pcs] = pca(waves);
            maxPC = min(10,size(pcs,2));
            
            % save feature info to user data
            app.FeaturesAxes.UserData.label = label;
            app.FeaturesAxes.UserData.feat = [spkIdx/Fs; ener; pcs(:,1:maxPC)'*waves];
            
            % update feature list
            featureList = [{'Time'; 'Energy'}; cellfun(@(n) sprintf('PC%i',n),num2cell(1:maxPC)','uni',false)];
            app.Feature1DropDown.Items = featureList;
            app.Feature2DropDown.Items = [{'None'}; featureList];
            app.Feature3DropDown.Items = [{'None'}; featureList];
            
            % reset polygon mask
            app.FeaturesAxes.UserData.mask = false(1,size(app.FeaturesAxes.UserData.feat,2));
            feature_poly(app,'reset')
            app.SetPolygonToggleButton.Value = false;
            app.FeaturePolygonCheckBox.Value = false;
        end
        
        % -----------------------------------------------------------------
        % UPDATE FEATURES AXES
        % -----------------------------------------------------------------
        function redraw_features(app)
            
            cla(app.FeaturesAxes)
            hold(app.FeaturesAxes,'on')
            
            featIdx = zeros(3,1);
            featName = cell(3,1);
            for ii = 1:length(featIdx)
                featIdx(ii) = find(ismember(app.Feature3DropDown.Items, app.(sprintf('Feature%iDropDown',ii)).Value));
                featName{ii} = app.Feature3DropDown.Items{featIdx(ii)};
            end
            featIdx = featIdx-1;
            featIdx(featIdx==0) = [];
            
            % pull features and labels
            feat = app.FeaturesAxes.UserData.feat(featIdx,:);
            label = app.FeaturesAxes.UserData.label;
            uql = unique(label);
            nUnit = length(uql);
            
            unitNo = find(ismember(app.WaveformUnitsListBox.Items,app.WaveformUnitsListBox.Value));
            
            % colormap
            if length(unitNo) == 1
                cmap = [0 0 0];
            else
                cmap = unit_colormap(app, size(app.Label.active,1));
                cmap = cmap(unitNo,:);
            end
            
            if any(app.FeaturesAxes.UserData.mask)
                nUnit = 2;
                cmap = [0 0 1; 0 0 0];
                label = app.FeaturesAxes.UserData.mask;
                uql = [true false];
            else
                app.FeaturesAxes.UserData.featIdx = featIdx;
            end
            
            % plot feature points
            switch length(featIdx)
                
                case 1 % histogram
                    
                    for ii = 1:nUnit
                        histogram(app.FeaturesAxes,...
                            feat(label==uql(ii)),...
                            'EdgeColor','none',...
                            'FaceColor',cmap(ii,:))
                    end
                    
                case 2 % scatter 2D
                    
                    for ii = 1:nUnit
                        scatter(app.FeaturesAxes,...
                            feat(1,label==uql(ii)),...
                            feat(2,label==uql(ii)),...
                            10, cmap(ii,:))
                    end
                    view(app.FeaturesAxes,2)
                    
                case 3 % scatter 3D
                    
                    for ii = 1:nUnit
                        scatter3(app.FeaturesAxes,...
                            feat(1,label==uql(ii)),...
                            feat(2,label==uql(ii)),...
                            feat(3,label==uql(ii)),...
                            10, cmap(ii,:))
                    end
                    view(app.FeaturesAxes,3)
            end
            
            % redraw feature polygon
            if any(app.FeaturesAxes.UserData.mask)
                feature_poly(app,'draw')
            end
            
            axis(app.FeaturesAxes,'auto')
            
            % format and label axes
            axStr = {'X','Y','Z'};
            for ii = 1:length(featName)
                if strcmp(featName{ii},'Time')
                    labelStr = 'Time (s)';
                    
                    % extend axis
                    set(app.FeaturesAxes, 'xlim', 0.02*range(get(app.FeaturesAxes,'xlim'))*[-1 1] + get(app.FeaturesAxes,'xlim'))
                else
                    labelStr = featName{ii};
                end
                app.FeaturesAxes.(sprintf('%sLabel',axStr{ii})).String = labelStr;
            end
        end
        
        % -----------------------------------------------------------------
        % RESOLVE SELECTED FEATURE CONFLICTS
        % -----------------------------------------------------------------
        function resolve_feature_conflicts(app, priorityOrder)
            values = cellfun(@(n) app.(sprintf('Feature%iDropDown',n)).Value, num2cell(1:3), 'uni', false);
            values = values(priorityOrder);
            for ii = 2:length(priorityOrder)
                if ismember(values{ii},values(1:ii-1)) && ~strcmp(values{ii},'None')
                    itemList = app.(sprintf('Feature%iDropDown',priorityOrder(ii))).Items;
                    values{ii} = itemList{find(~ismember(itemList,values(1:ii)),1)};
                    app.(sprintf('Feature%iDropDown',priorityOrder(ii))).Value = values{ii};
                end
            end
        end
        
        % -----------------------------------------------------------------
        % FEATURE POLYGON
        % -----------------------------------------------------------------
        function feature_poly(app,action,newPt)
            switch action
                case 'reset'
                    if ~isempty(app.FeaturesAxes.UserData.poly)
                        delete(app.FeaturesAxes.Children(arrayfun(@(x) strcmp(x.Tag,'poly'), app.FeaturesAxes.Children)))
                    end
                    app.FeaturesAxes.UserData.poly = [];
                    
                case 'set'
                    hold(app.FeaturesAxes,'on')
                    pts = [app.FeaturesAxes.UserData.poly, newPt(:)];
                    plot(app.FeaturesAxes, pts(1,end), pts(2,end), 'r+', 'Tag', 'poly', 'MarkerSize',10, 'LineWidth',2)
                    if size(pts,2) > 1
                        plot(app.FeaturesAxes, pts(1,end-1:end), pts(2,end-1:end), 'r-', 'Tag', 'poly')
                    end
                    app.FeaturesAxes.UserData.poly = pts;
                    
                case 'close'
                    hold(app.FeaturesAxes,'on')
                    pts = app.FeaturesAxes.UserData.poly;
                    plot(app.FeaturesAxes, pts(1,[1 end]), pts(2,[1 end]), 'r-', 'Tag', 'poly')
                    update_poly_mask(app);
                    
                case 'draw'
                    hold(app.FeaturesAxes,'on')
                    pts = app.FeaturesAxes.UserData.poly(:,[end,1:end]);
                    for ii = 1:size(pts,2)-1
                        plot(app.FeaturesAxes, pts(1,ii), pts(2,ii), 'r+', 'Tag', 'poly', 'MarkerSize',10, 'LineWidth',2)
                        plot(app.FeaturesAxes, pts(1,[ii ii+1]), pts(2,[ii ii+1]), 'r-', 'Tag', 'poly')
                    end
            end
        end
        
        % -----------------------------------------------------------------
        % UPDATE POLYGON MASK
        % -----------------------------------------------------------------
        % references
        % https://www.geeksforgeeks.org/how-to-check-if-a-given-point-lies-inside-a-polygon/
        % https://www.geeksforgeeks.org/check-if-two-given-line-segments-intersect/
        % https://www.geeksforgeeks.org/orientation-3-ordered-points/
        function update_poly_mask(app)
            % polygon segments
            polyPts = app.FeaturesAxes.UserData.poly(1:2,[end,1:end]);
            polySeg = cat(3,polyPts(:,1:end-1),polyPts(:,2:end));
            
            % feature point vectors
            featPts = app.FeaturesAxes.UserData.feat(app.FeaturesAxes.UserData.featIdx,:);
            featSeg = cat(3, featPts, [app.FeaturesAxes.XLim(2)*ones(1,size(featPts,2)); featPts(2,:)]);
            
            % function to determine orientation of 2 vectors
            % (1: clockwise, 0: collinear, -1: counterclockwise)
            segOrient = @(p0,p1,p2) sign((p1(2,:)-p0(2,:)).*(p2(1,:)-p1(1,:))...
                - (p2(2,:)-p1(2,:)).*(p1(1,:)-p0(1,:)));
            
            % count the number of times each feature segment intersects
            % with the polygon
            nIntersect = zeros(1,size(featPts,2));
            
            for iSeg = 1:size(polySeg,2)
                
                nIntersect = nIntersect + double( ...
                    (segOrient(featSeg(:,:,1),featSeg(:,:,2),polySeg(:,iSeg,1)) ...
                    ~= segOrient(featSeg(:,:,1),featSeg(:,:,2),polySeg(:,iSeg,2))) ...
                    & (segOrient(polySeg(:,iSeg,1),polySeg(:,iSeg,2),featSeg(:,:,1)) ...
                    ~= segOrient(polySeg(:,iSeg,1),polySeg(:,iSeg,2),featSeg(:,:,2))));
            end
            
            % mask points in polygon
            app.FeaturesAxes.UserData.mask = mod(nIntersect,2)~=0;
            
            % redraw features
            redraw_features(app)
            
            % redraw waveforms
            redraw_waveforms(app);
        end
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            read_defaults(app);
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            cp = get(app.FeaturesAxes, 'CurrentPoint');
            if strcmp(app.TabGroup.SelectedTab.Title, 'Waveforms')
                if app.SetPolygonToggleButton.Value ...
                        && (all(cp(:,1)>=app.FeaturesAxes.XLim(1)) && any(cp(:,1)<=app.FeaturesAxes.XLim(2))) ...
                        && (all(cp(:,2)>=app.FeaturesAxes.YLim(1)) && any(cp(:,2)<=app.FeaturesAxes.YLim(2))) ...
                        && (all(cp(:,3)>=app.FeaturesAxes.ZLim(1)) && any(cp(:,3)<=app.FeaturesAxes.ZLim(2)))
                    
                    feature_poly(app,'set',cp(1,:))
                end
            end
        end

        % Value changed function: SavePathEditField
        function SavePathEditFieldValueChanged(app, event)
            % clean input
            value = app.SavePathEditField.Value;
            validIdx = regexp(value,['\' filesep '*\' filesep]);
            if ~isempty(validIdx)
                app.SavePathEditField.Value = value(validIdx(1):validIdx(end));
            else
                app.SummaryTextArea.Value = [app.SummaryTextArea.Value; sprintf('Enter valid file path')];
                app.SavePathEditField.Value = '';
            end
        end

        % Button pushed function: BrowsePushButton
        function BrowsePushButtonPushed(app, event)
            pth = uigetdir('','Myosort processed directory');
            app.SavePathEditField.Value = pth;
        end

        % Button pushed function: ImportPushButton
        function ImportPushButtonPushed(app, event)
            % print status
            app.SummaryTextArea.Value = 'Importing data . . . ';
            pause(1)
            
            % load data (temporary workaround for stability)
            app.X = evalin('base','EMG.data''');
            
            % import static variables
            load([app.SavePathEditField.Value 'noise_cov'],'Cinv')
            load([app.SavePathEditField.Value 'noise_std'],'noiseStd')
            load([app.SavePathEditField.Value 'batch_indices'],'batchIdx','isNoiseBatch')
            app.Cinv = Cinv;
            app.noiseStd = noiseStd;
            
            % restrict sample indices to non-noise components
            batchIdx = batchIdx(~isNoiseBatch);
            app.botmTestIdx = cat(2,batchIdx{:});
            
            % reset GUI to default values
            write_defaults(app);
            
            % read sort options
            read_sort_options(app);
            
            % reset GUI components
            reset_gui(app);
            
            % initialize spike data
            init_spike_data(app);
            
            % update template plots
            redraw_templates(app,{'active','inactive'});
            
            % initialize timeseries frame sample indices
            Fs = app.SampleRateEditField.Value;
            frameDur = app.TimeseriesFrameEditField.Value;
            app.TimeseriesFrameEditField.UserData.Properties.tIdx = 0:(Fs*frameDur-1);
            
            % intialize timeseries axes data
            mask = ismember(app.TimeseriesChannelsListBox.Items,app.TimeseriesChannelsListBox.Value);
            plot(app.TimeseriesAxes,zeros(2,nnz(mask)),zeros(2,nnz(mask)),'k');
            
            % update timeseries axes
            redraw_timeseries(app);
            
            % update waveform features
            update_features(app);
            
            % update waveforms axes
            redraw_waveforms(app);
            
            % update features axes
            redraw_features(app);
            
            % print status
            app.SummaryTextArea.Value = [app.SummaryTextArea.Value{1},...
                sprintf('Done\n')];
        end

        % Button pushed function: ExportPushButton
        function ExportPushButtonPushed(app, event)
                % print status
                app.SummaryTextArea.Value = [app.SummaryTextArea.Value{1},...
                    sprintf('\nExporting data . . . ')];
                pause(1)
                % load data
                load([app.SavePathEditField.Value 'spikes'],'Spk')
                load([app.SavePathEditField.Value 'labels'],'Lab')
                load([app.SavePathEditField.Value 'templates'],'W')
                % center templates
%                 w = app.Templates.active;
%                 wEnv = smooth1D(abs(w),app.SampleRateEditField.Value,'gau','sd',1e-3,'dim',1);
%                 waveLen = size(w,1);
%                 [~,com] = max(squeeze(mean(wEnv,2)),[],1);
%                 for iUn = 1:size(app.Label.active,1)
%                     lid = app.Label.active(iUn,:);
%                     app.spikes(lid) = app.spikes(lid) - (1+waveLen/2-com(iUn));
%                 end
                label = sum((1:size(app.Label.active,1))'.*app.Label.active,1);
                spkIdx = app.spikes(label>0);
                label = label(label>0);
                ws = myo.spktrig(app.X, spkIdx, size(app.Templates.active,1));
                app.Templates.active = myo.wavetemplate(ws, label, false);
                % save data
                saveField = 'final';
                %             if isfield(Spk,'template_matching')
                %                 saveField = 'final';
                %             else
                %                 saveField = 'manual';
                %             end
                Spk.(saveField) = spkIdx;
                Lab.(saveField) = label;
                W.(saveField) = app.Templates.active;
                save([app.SavePathEditField.Value 'spikes'],'Spk')
                save([app.SavePathEditField.Value 'labels'],'Lab')
                save([app.SavePathEditField.Value 'templates'],'W')
                % print status
                app.SummaryTextArea.Value = [app.SummaryTextArea.Value{end},...
                    sprintf('Done\n')];
                % update axes
                redraw_templates(app,{'active','inactive'});
                pause(1)
                redraw_waveforms(app);
                update_features(app);
                redraw_features(app);
        end

        % Button pushed function: RunPushButton
        function RunPushButtonPushed(app, event)
            
        end

        % Value changed function: TimeseriesChannelsListBox
        function TimeseriesChannelsListBoxValueChanged(app, event)
            cla(app.TimeseriesAxes)
            mask = ismember(app.TimeseriesChannelsListBox.Items,app.TimeseriesChannelsListBox.Value);
            plot(app.TimeseriesAxes,zeros(2,nnz(mask)),zeros(2,nnz(mask)),'k');
            redraw_timeseries(app);
        end

        % Value changed function: TimeseriesAxesUnitsSwitch
        function TimeseriesAxesUnitsSwitchValueChanged(app, event)
            redraw_timeseries(app);
            redraw_templates(app,{'active','inactive'});
            redraw_waveforms(app);
        end

        % Value changed function: OverlayListBox
        function OverlayListBoxValueChanged(app, event)
            value = app.OverlayListBox.Value;
            
        end

        % Button pushed function: TimeseriesBotmPushButton
        function TimeseriesBotmPushButtonPushed(app, event)
            
        end

        % Value changed function: TimeseriesFrameEditField
        function TimeseriesFrameEditFieldValueChanged(app, event)
            Fs = app.SampleRateEditField.Value;
            frameDur = app.TimeseriesFrameEditField.Value;
            app.TimeseriesFrameEditField.UserData.Properties.tIdx = 0:(Fs*frameDur-1);
            redraw_timeseries(app);
        end

        % Button pushed function: RandomFramePushButton
        function RandomFramePushButtonPushed(app, event)
            value = rand*diff(app.TimeseriesSlider.Limits) + app.TimeseriesSlider.Limits(1);
            app.TimeseriesSlider.Value = value;
            app.TimeseriesPositionEditField.Value = value;
            redraw_timeseries(app);
        end

        % Value changed function: TimeseriesPositionEditField
        function TimeseriesPositionEditFieldValueChanged(app, event)
            app.TimeseriesSlider.Value = app.TimeseriesPositionEditField.Value;
            redraw_timeseries(app);
        end

        % Value changed function: TimeseriesSlider
        function TimeseriesSliderValueChanged(app, event)
            app.TimeseriesPositionEditField.Value = app.TimeseriesSlider.Value;
            redraw_timeseries(app);
        end

        % Value changed function: ActiveTemplatesListBox
        function ActiveTemplatesListBoxValueChanged(app, event)
            if app.PlotSelectedActiveCheckBox.Value
                redraw_templates(app,{'active'});
            end
        end

        % Value changed function: InactiveTemplatesListBox
        function InactiveTemplatesListBoxValueChanged(app, event)
            if app.PlotSelectedInactiveCheckBox.Value
                redraw_templates(app,{'inactive'});
            end
        end

        % Button pushed function: InactivateTemplatePushButton
        function InactivateTemplatePushButtonPushed(app, event)
            unitNo = find(ismember(app.ActiveTemplatesListBox.Items,app.ActiveTemplatesListBox.Value));
            % add units to inactive set
            app.Label.inactive = [app.Label.inactive; app.Label.active(unitNo,:)];
            app.Templates.inactive = cat(3, app.Templates.inactive, app.Templates.active(:,:,unitNo));
            % remove units from active set
            app.Label.active(unitNo,:) = [];
            app.Templates.active(:,:,unitNo) = [];
            app.ActiveTemplatesListBox.UserData.goodUnit(unitNo) = [];
            % sort inactive
            [~,srtIdx] = sort(squeeze(mean(sum(app.Templates.inactive.^2,1))));
            app.Label.inactive = app.Label.inactive(srtIdx,:);
            app.Templates.inactive = app.Templates.inactive(:,:,srtIdx);
            % check if removed unit was plotted in waveform/feature view
            if min(unitNo) < find(ismember(app.WaveformUnitsListBox.Items,app.WaveformUnitsListBox.Value),1,'first')
                updateWaveFeat = true;
            else
                updateWaveFeat = false;
            end
            % update listboxes and axes
            update_units_listboxes(app);
            redraw_templates(app,{'active','inactive'});
            pause(1)
            if updateWaveFeat
                update_features(app);
                redraw_waveforms(app);
                redraw_features(app);
            end
        end

        % Button pushed function: ActivateTemplatePushButton
        function ActivateTemplatePushButtonPushed(app, event)
            unitNo = find(ismember(app.InactiveTemplatesListBox.Items,app.InactiveTemplatesListBox.Value));
            % add units to active set
            app.Label.active = [app.Label.active; app.Label.inactive(unitNo,:)];
            app.Templates.active = cat(3, app.Templates.active, app.Templates.inactive(:,:,unitNo));
            app.ActiveTemplatesListBox.UserData.goodUnit = [app.ActiveTemplatesListBox.UserData.goodUnit; false(length(unitNo),1)];
            % remove units from inactive set
            app.Label.inactive(unitNo,:) = [];
            app.Templates.inactive(:,:,unitNo) = [];
            % sort active
            [~,srtIdx] = sort(squeeze(mean(sum(app.Templates.active.^2,1))));
            app.Label.active = app.Label.active(srtIdx,:);
            app.Templates.active = app.Templates.active(:,:,srtIdx);
            app.ActiveTemplatesListBox.UserData.goodUnit = app.ActiveTemplatesListBox.UserData.goodUnit(srtIdx);
            % update listboxes
            update_units_listboxes(app);
            redraw_templates(app,{'active','inactive'});
            pause(1)
            update_features(app);
            redraw_waveforms(app);
            redraw_features(app);
        end

        % Button pushed function: ToggleGoodTemplatePushButton
        function ToggleGoodTemplatePushButtonPushed(app, event)
            unitNo = find(ismember(app.ActiveTemplatesListBox.Items,app.ActiveTemplatesListBox.Value));
            % flip selected unit status
            unitStatus = app.ActiveTemplatesListBox.UserData.goodUnit(unitNo);
            unitStatus = logical(mod(double(unitStatus)+1,2));
            app.ActiveTemplatesListBox.UserData.goodUnit(unitNo) = unitStatus;
            % update listbox
            update_units_listboxes(app);
            % preserve selected
            app.ActiveTemplatesListBox.Value = app.ActiveTemplatesListBox.Items(unitNo);
        end

        % Button pushed function: DeleteTemplatePushButton
        function DeleteTemplatePushButtonPushed(app, event)
            % delete spikes and labels associated with selected unit
            unitNo = find(ismember(app.ActiveTemplatesListBox.Items,app.ActiveTemplatesListBox.Value));
            rmvIdx = app.Label.active(unitNo,:);
            app.spikes(rmvIdx) = [];
            app.waveforms(:,:,rmvIdx) = [];
            app.Label.active(unitNo,:) = [];
            app.Label.active(:,rmvIdx) = [];
            app.Label.inactive(:,rmvIdx) = [];
            app.Templates.active(:,:,unitNo) = [];
            app.ActiveTemplatesListBox.UserData.goodUnit(unitNo) = [];
            % check if removed unit was plotted in waveform/feature view
            if min(unitNo) < find(ismember(app.WaveformUnitsListBox.Items,app.WaveformUnitsListBox.Value),1,'first')
                updateWaveFeat = true;
            else
                updateWaveFeat = false;
            end
            % update listboxes
            update_units_listboxes(app);
            redraw_templates(app,{'active'});
            pause(1)
            if updateWaveFeat
                update_features(app);
                redraw_waveforms(app);
                redraw_features(app);
            end
        end

        % Value changed function: PlotSelectedActiveCheckBox
        function PlotSelectedActiveCheckBoxValueChanged(app, event)
            redraw_templates(app,{'active'});
        end

        % Value changed function: PlotSelectedInactiveCheckBox
        function PlotSelectedInactiveCheckBoxValueChanged(app, event)
            redraw_templates(app,{'inactive'});
        end

        % Button pushed function: ShiftActiveTemplatePushButton
        function ShiftActiveTemplatePushButtonPushed(app, event)
            % shift active templates
            unitNo = find(ismember(app.ActiveTemplatesListBox.Items,app.ActiveTemplatesListBox.Value));
            dS = round(app.SampleRateEditField.Value * app.ShiftActiveTemplateEditField.Value/1e3);
            for ii = 1:length(unitNo)
                lid = app.Label.active(unitNo(ii),:);
                app.spikes(lid) = app.spikes(lid) + dS;
                app.waveforms(:,:,lid) = myo.spktrig(app.X, app.spikes(lid), size(app.Templates.active,1));
            end
            label = sum((1:size(app.Label.active,1))'.*app.Label.active,1);
            app.Templates.active = myo.wavetemplate(app.waveforms, label, false);
            % check if updated unit was plotted in waveform/feature view
            if ismember(unitNo, find(ismember(app.WaveformUnitsListBox.Items,app.WaveformUnitsListBox.Value)))
                updateWaveFeat = true;
            else
                updateWaveFeat = false;
            end
            % update templates
            redraw_templates(app,{'active','inactive'});
            pause(1)
            if updateWaveFeat
                redraw_waveforms(app);
                update_features(app);
                redraw_features(app);
            end
        end

        % Button pushed function: ResetTemplatesPushButton
        function ResetTemplatesPushButtonPushed(app, event)
            % throw warning
            selection = uiconfirm(uifigure,'This will lose all progress. Are you sure you want to reset templates?','Confirm Reset',...
                'Options',{'Yes','No'}, 'Icon','warning', 'DefaultOption',2);
            if ~strcmp(selection,'Yes')
                return
            end
            % reset
            init_spike_data(app);
            redraw_templates(app,{'active','inactive'});
            redraw_waveforms(app);
            update_features(app);
            redraw_features(app);
        end

        % Button pushed function: TemplatesBotmPushButton
        function TemplatesBotmPushButtonPushed(app, event)
                Fs = app.SampleRateEditField.Value;
                [nChan,nSamp] = size(app.X);
                
                % batch length
                batchLen = min(ceil(Fs*app.TemplatesBotmBatchDurationEditField.Value),nSamp);
                batchLen = batchLen + mod(batchLen,2);
                nBatch = app.TemplatesBotmBatchesEditField.Value;
                
                % draw time limits at random
                batchLim = datasample(app.botmTestIdx(app.botmTestIdx>batchLen/2 & app.botmTestIdx<(nSamp-batchLen/2+1)),nBatch,'Replace',false)' + [-batchLen/2, batchLen/2-1];
                
                % close previous test figures
                if isfield(app.TemplatesBotmPushButton.UserData, 'testFigs') && ~isempty(app.TemplatesBotmPushButton.UserData.testFigs)
                    close(intersect(get(groot,'Children'),app.TemplatesBotmPushButton.UserData.testFigs))
                end
                
                % get open figures
                openFigs = get(groot,'Children');
                
                % test fit
                nUnit = length(app.ActiveTemplatesListBox.Items);
                resid = zeros(nChan,nUnit,nBatch);
                for ii = 1:nBatch
                    [~,~,resEner] = botm(app.X(:,batchLim(ii,1):batchLim(ii,2))', Fs, app.Templates.active, app.Cinv, 'refDur',6e-3, 'plot',{'fitTight'});
                    resid(:,:,ii) = resEner{1};
                end
                
                % plot residuals
                G1 = repmat(1:nUnit,nChan,1,nBatch);
                G2 = repmat((1:nChan)',1,nUnit,nBatch);
                figure
                boxplot(resid(:),[G1(:),G2(:)],'FactorGap',[20 1])
                xlabel(sprintf('\nunit\nchannel'))
                ylabel('residual')
                ylim([0 max(get(gca,'YLim'))])
                
                % save new figure list
                app.TemplatesBotmPushButton.UserData.testFigs = setdiff(get(groot,'Children'),openFigs);
        end

        % Value changed function: WaveformChannelsListBox
        function WaveformChannelsListBoxValueChanged(app, event)
            update_features(app);
            redraw_waveforms(app);
            redraw_features(app);
        end

        % Value changed function: WaveformUnitsListBox
        function WaveformUnitsListBoxValueChanged(app, event)
            update_features(app);
            redraw_waveforms(app);
            redraw_features(app);
        end

        % Value changed function: OverlayWaveformsCheckBox
        function OverlayWaveformsCheckBoxValueChanged(app, event)
            value = app.OverlayWaveformsCheckBox.Value;
            
        end

        % Button pushed function: MergeWaveformsPushButton
        function MergeWaveformsPushButtonPushed(app, event)
            unitNo = find(ismember(app.WaveformUnitsListBox.Items,app.WaveformUnitsListBox.Value));
            if length(unitNo) <= 1
                return
            end
            if strcmp(app.TemplatesSourceDropDown.Value,'template_matching')
                % combine templates
                w = app.Templates.active;
                w(:,:,unitNo(1)) = mean(w(:,:,unitNo),3);
                w(:,:,unitNo(2:end)) = [];
                % combine labels
                app.Label.active(unitNo(1),:) = any(app.Label.active(unitNo,:),1);
                app.Label.active(unitNo(2:end),:) = [];
                % reset "good" markers
                app.ActiveTemplatesListBox.UserData.goodUnit(unitNo(1)) = false;
                app.ActiveTemplatesListBox.UserData.goodUnit(unitNo(2:end)) = [];
                % re-sort active
                [~,srtIdx] = sort(squeeze(mean(sum(w.^2,1),2)));
                app.Label.active = app.Label.active(srtIdx,:);
                app.Templates.active = w(:,:,srtIdx);
                app.ActiveTemplatesListBox.UserData.goodUnit = app.ActiveTemplatesListBox.UserData.goodUnit(srtIdx);
            else
                % compute optimal shifts
                [~,~,optLag,~,lags] = wavexcorr(app.Templates.active(:,:,unitNo));
                optShift = sign(optLag).*lags(abs(optLag));
                % snap spikes to most central template
                [~,com] = max(squeeze(mean(smooth1D(abs(app.Templates.active(:,:,unitNo)),app.SampleRateEditField.Value,'gau','sd',5e-4,'dim',1),2)));
                [~,target] = min(abs(com-size(app.Templates.active,1)/2));
                for ii = 1:length(unitNo)
                    if ii ~= target
                        lid = app.Label.active(unitNo(ii),:);
                        app.spikes(lid) = app.spikes(lid) - optShift(target,ii);
                        app.waveforms(:,:,lid) = myo.spktrig(app.X, app.spikes(lid), size(app.Templates.active,1));
                    end
                end
                % combine labels
                app.Label.active(unitNo(1),:) = any(app.Label.active(unitNo,:),1);
                app.Label.active(unitNo(2:end),:) = [];
                % reset "good" markers
                app.ActiveTemplatesListBox.UserData.goodUnit(unitNo(1)) = false;
                app.ActiveTemplatesListBox.UserData.goodUnit(unitNo(2:end)) = [];
                % re-sort active
                label = sum((1:size(app.Label.active,1))'.*app.Label.active,1);
                w = myo.wavetemplate(app.waveforms, label, false);
                [~,srtIdx] = sort(squeeze(mean(sum(w.^2,1),2)));
                app.Label.active = app.Label.active(srtIdx,:);
                app.Templates.active = w(:,:,srtIdx);
                app.ActiveTemplatesListBox.UserData.goodUnit = app.ActiveTemplatesListBox.UserData.goodUnit(srtIdx);
            end
            % reset feature polygon
            app.SetPolygonToggleButton.Value = false;
            feature_poly(app,'reset')
            % update units list and templates
            update_units_listboxes(app);
            redraw_templates(app,{'active'});
            pause(1)
            update_features(app);
            redraw_waveforms(app);
            redraw_features(app);
        end

        % Value changed function: WaveformPlotStyleSwitch
        function WaveformPlotStyleSwitchValueChanged(app, event)
            redraw_waveforms(app);
        end

        % Value changed function: WaveformMaxLinesEditField
        function WaveformMaxLinesEditFieldValueChanged(app, event)
            if strcmp(app.WaveformPlotStyleSwitch.Value, 'Line')
                redraw_waveforms(app);
            end
        end

        % Button pushed function: ResampleWaveformsPushButton
        function ResampleWaveformsPushButtonPushed(app, event)
            if strcmp(app.WaveformPlotStyleSwitch.Value, 'Line')
                redraw_waveforms(app);
            end
        end

        % Button pushed function: RemoveOutliersPushButton
        function RemoveOutliersPushButtonPushed(app, event)
            
        end

        % Value changed function: Feature1DropDown
        function Feature1DropDownValueChanged(app, event)
            resolve_feature_conflicts(app,1:3);
            redraw_features(app);
        end

        % Value changed function: Feature2DropDown
        function Feature2DropDownValueChanged(app, event)
            resolve_feature_conflicts(app,[2 1 3]);
            redraw_features(app);
        end

        % Value changed function: Feature3DropDown
        function Feature3DropDownValueChanged(app, event)
            resolve_feature_conflicts(app,[3 1 2]);
            redraw_features(app);
        end

        % Value changed function: FeaturePolygonCheckBox
        function FeaturePolygonCheckBoxValueChanged(app, event)
            if app.FeaturePolygonCheckBox.Value==1 && any(app.FeaturesAxes.UserData.mask)
                redraw_waveforms(app);
                redraw_features(app);
            end
        end

        % Value changed function: SetPolygonToggleButton
        function SetPolygonToggleButtonValueChanged(app, event)
            if app.SetPolygonToggleButton.Value == 1
                feature_poly(app,'reset');
            else
                if ~isempty(app.FeaturesAxes.UserData.poly)
                    feature_poly(app,'close');
                end
            end
        end

        % Button pushed function: ClearPolygonPushButton
        function ClearPolygonPushButtonPushed(app, event)
            feature_poly(app,'reset');
            update_features(app);
            redraw_features(app);
            redraw_waveforms(app);
        end

        % Button pushed function: SplitWaveformsPushButton
        function SplitWaveformsPushButtonPushed(app, event)
            % split active unit using mask
            unitNo = find(ismember(app.WaveformUnitsListBox.Items,app.WaveformUnitsListBox.Value));
            labelIdx = find(app.Label.active(unitNo,:));
            app.Label.active(unitNo,:) = [];
            app.Label.active = [app.Label.active; false(2,length(app.spikes))];
            app.Label.active(end-1,labelIdx(app.FeaturesAxes.UserData.mask)) = true;
            app.Label.active(end,labelIdx(~app.FeaturesAxes.UserData.mask)) = true;
            % reset "good" markers
            app.ActiveTemplatesListBox.UserData.goodUnit(unitNo) = [];
            app.ActiveTemplatesListBox.UserData.goodUnit = [app.ActiveTemplatesListBox.UserData.goodUnit; false(2,1)];
            % re-sort active
            label = sum((1:size(app.Label.active,1))'.*app.Label.active,1);
            w = myo.wavetemplate(app.waveforms, label, false);
            [~,srtIdx] = sort(squeeze(mean(sum(w.^2,1),2)));
            app.Label.active = app.Label.active(srtIdx,:);
            app.Templates.active = w(:,:,srtIdx);
            app.ActiveTemplatesListBox.UserData.goodUnit = app.ActiveTemplatesListBox.UserData.goodUnit(srtIdx);
            % reset feature polygon
            app.SetPolygonToggleButton.Value = false;
            feature_poly(app,'reset')
            % update units list and templates
            update_units_listboxes(app);
            redraw_templates(app,{'active'});
            pause(1)
            update_features(app);
            redraw_features(app);
            redraw_waveforms(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MyosortTab = uitab(app.TabGroup);
            app.MyosortTab.Title = 'Myosort';
            app.VetBatchDurationLabel = uilabel(app.MyosortTab);
            app.VetBatchDurationLabel.Text = 's';
            app.ClusterTemplateDurationLabel = uilabel(app.MyosortTab);
            app.ClusterTemplateDurationLabel.Text = 's';
            app.AlignWaveformDurationLabel = uilabel(app.MyosortTab);
            app.AlignWaveformDurationLabel.Text = 's';
            app.FilterHighCutLabel = uilabel(app.MyosortTab);
            app.FilterHighCutLabel.Text = 'Hz';
            app.FilterLowCutLabel = uilabel(app.MyosortTab);
            app.FilterLowCutLabel.Text = 'Hz';
            app.SummaryTextArea = uitextarea(app.MyosortTab);
            app.SummaryTextArea.Editable = 'off';
            app.SampleRateLabel = uilabel(app.MyosortTab);
            app.SampleRateLabel.Text = 'Hz';
            app.TemplateMatchingLabel = uilabel(app.MyosortTab);
            app.TemplateMatchingLabel.Text = 'Template Matching';
            app.NoiseCovarianceLabel = uilabel(app.MyosortTab);
            app.NoiseCovarianceLabel.Text = 'Noise Covariance';
            app.VetLabel = uilabel(app.MyosortTab);
            app.VetLabel.Text = 'Vet';
            app.MergeLabel = uilabel(app.MyosortTab);
            app.MergeLabel.Text = 'Merge';
            app.TriageLabel = uilabel(app.MyosortTab);
            app.TriageLabel.Text = 'Triage';
            app.ClusterLabel = uilabel(app.MyosortTab);
            app.ClusterLabel.Text = 'Cluster';
            app.AlignLabel = uilabel(app.MyosortTab);
            app.AlignLabel.Text = 'Align';
            app.DetectLabel = uilabel(app.MyosortTab);
            app.DetectLabel.Text = 'Detect';
            app.FilterLabel = uilabel(app.MyosortTab);
            app.FilterLabel.Text = 'Filter';
            app.RunPushButton = uibutton(app.MyosortTab, 'push');
            app.RunPushButton.ButtonPushedFcn = createCallbackFcn(app, @RunPushButtonPushed, true);
            app.RunPushButton.Text = 'Run';
            app.ExportPushButton = uibutton(app.MyosortTab, 'push');
            app.ExportPushButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPushButtonPushed, true);
            app.ExportPushButton.Text = 'Export';
            app.ImportPushButton = uibutton(app.MyosortTab, 'push');
            app.ImportPushButton.ButtonPushedFcn = createCallbackFcn(app, @ImportPushButtonPushed, true);
            app.ImportPushButton.Text = 'Import';
            app.BrowsePushButton = uibutton(app.MyosortTab, 'push');
            app.BrowsePushButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsePushButtonPushed, true);
            app.BrowsePushButton.Text = 'Browse';
            app.TemplateMatchingCheckBox = uicheckbox(app.MyosortTab);
            app.TemplateMatchingCheckBox.Text = '';
            app.VetCheckBox = uicheckbox(app.MyosortTab);
            app.VetCheckBox.Text = '';
            app.NoiseCovarianceCheckBox = uicheckbox(app.MyosortTab);
            app.NoiseCovarianceCheckBox.Text = '';
            app.MergeCheckBox = uicheckbox(app.MyosortTab);
            app.MergeCheckBox.Text = '';
            app.TriageCheckBox = uicheckbox(app.MyosortTab);
            app.TriageCheckBox.Text = '';
            app.ClusterCheckBox = uicheckbox(app.MyosortTab);
            app.ClusterCheckBox.Text = '';
            app.AlignCheckBox = uicheckbox(app.MyosortTab);
            app.AlignCheckBox.Text = '';
            app.DetectCheckBox = uicheckbox(app.MyosortTab);
            app.DetectCheckBox.Text = '';
            app.FilterCheckBox = uicheckbox(app.MyosortTab);
            app.FilterCheckBox.Text = '';
            app.AlphaEditFieldLabel = uilabel(app.MyosortTab);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.NoiseCovarianceAlphaEditField = uieditfield(app.MyosortTab, 'numeric');
            app.NoiseCovarianceAlphaEditField.Limits = [0 1];
            app.NoiseCovarianceAlphaEditField.Value = 0.5;
            app.BinsEditFieldLabel = uilabel(app.MyosortTab);
            app.BinsEditFieldLabel.Text = 'Bins';
            app.TriageBinsEditField = uieditfield(app.MyosortTab, 'numeric');
            app.TriageBinsEditField.Limits = [0 10000];
            app.TriageBinsEditField.RoundFractionalValues = 'on';
            app.TriageBinsEditField.ValueDisplayFormat = '%i';
            app.TriageBinsEditField.Value = 100;
            app.RoundsEditFieldLabel_2 = uilabel(app.MyosortTab);
            app.RoundsEditFieldLabel_2.Text = 'Rounds';
            app.VetRoundsEditField = uieditfield(app.MyosortTab, 'numeric');
            app.VetRoundsEditField.Limits = [0 1000];
            app.VetRoundsEditField.RoundFractionalValues = 'on';
            app.VetRoundsEditField.ValueDisplayFormat = '%i';
            app.VetRoundsEditField.Value = 30;
            app.CVThresholdEditFieldLabel = uilabel(app.MyosortTab);
            app.CVThresholdEditFieldLabel.Text = 'CV Threshold';
            app.VetCVThresholdEditField = uieditfield(app.MyosortTab, 'numeric');
            app.VetCVThresholdEditField.Limits = [0 Inf];
            app.VetCVThresholdEditField.Value = 1;
            app.MinCountEditFieldLabel = uilabel(app.MyosortTab);
            app.MinCountEditFieldLabel.Text = 'Min Count';
            app.TriageMinCountEditField = uieditfield(app.MyosortTab, 'numeric');
            app.TriageMinCountEditField.Limits = [0 Inf];
            app.TriageMinCountEditField.RoundFractionalValues = 'on';
            app.TriageMinCountEditField.ValueDisplayFormat = '%i';
            app.TriageMinCountEditField.Value = 5;
            app.RoundsEditFieldLabel = uilabel(app.MyosortTab);
            app.RoundsEditFieldLabel.Text = 'Rounds';
            app.ClusterRoundsEditField = uieditfield(app.MyosortTab, 'numeric');
            app.ClusterRoundsEditField.Limits = [0 50];
            app.ClusterRoundsEditField.RoundFractionalValues = 'on';
            app.ClusterRoundsEditField.ValueDisplayFormat = '%i';
            app.ClusterRoundsEditField.Value = 10;
            app.FeaturesEditFieldLabel = uilabel(app.MyosortTab);
            app.FeaturesEditFieldLabel.Text = 'Features';
            app.ClusterFeaturesEditField = uieditfield(app.MyosortTab, 'numeric');
            app.ClusterFeaturesEditField.Limits = [0 20];
            app.ClusterFeaturesEditField.RoundFractionalValues = 'on';
            app.ClusterFeaturesEditField.ValueDisplayFormat = '%i';
            app.ClusterFeaturesEditField.Value = 3;
            app.BatchSizeEditFieldLabel = uilabel(app.MyosortTab);
            app.BatchSizeEditFieldLabel.Text = 'Batch Size';
            app.AlignBatchSizeEditField = uieditfield(app.MyosortTab, 'numeric');
            app.AlignBatchSizeEditField.Limits = [0 Inf];
            app.AlignBatchSizeEditField.RoundFractionalValues = 'on';
            app.AlignBatchSizeEditField.ValueDisplayFormat = '%i';
            app.AlignBatchSizeEditField.Value = 100;
            app.ThresholdEditFieldLabel = uilabel(app.MyosortTab);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.DetectThresholdEditField = uieditfield(app.MyosortTab, 'numeric');
            app.DetectThresholdEditField.Limits = [0 10];
            app.DetectThresholdEditField.RoundFractionalValues = 'on';
            app.DetectThresholdEditField.ValueDisplayFormat = '%.1f';
            app.DetectThresholdEditField.Value = 6;
            app.RefractoryDurationLabel = uilabel(app.MyosortTab);
            app.RefractoryDurationLabel.Text = 'Refractory Duration';
            app.TemplateMatchingRefractoryDurationEditField = uieditfield(app.MyosortTab, 'numeric');
            app.TemplateMatchingRefractoryDurationEditField.Limits = [0 1];
            app.TemplateMatchingRefractoryDurationEditField.Value = 0.001;
            app.BatchDurationEditFieldLabel = uilabel(app.MyosortTab);
            app.BatchDurationEditFieldLabel.Text = 'Batch Duration';
            app.VetBatchDurationEditField = uieditfield(app.MyosortTab, 'numeric');
            app.VetBatchDurationEditField.Limits = [0 Inf];
            app.VetBatchDurationEditField.Value = 10;
            app.ResidualThresholdEditFieldLabel = uilabel(app.MyosortTab);
            app.ResidualThresholdEditFieldLabel.Text = 'Residual Threshold';
            app.VetResidualThresholdEditField = uieditfield(app.MyosortTab, 'numeric');
            app.VetResidualThresholdEditField.Limits = [0 Inf];
            app.VetResidualThresholdEditField.Value = 1.1;
            app.MisalignThresholdEditFieldLabel = uilabel(app.MyosortTab);
            app.MisalignThresholdEditFieldLabel.Text = 'Misalign Threshold';
            app.VetMisalignThresholdEditField = uieditfield(app.MyosortTab, 'numeric');
            app.VetMisalignThresholdEditField.Limits = [0 1];
            app.VetMisalignThresholdEditField.Value = 0.1;
            app.ThresholdLabel = uilabel(app.MyosortTab);
            app.ThresholdLabel.Text = 'Threshold';
            app.MergeThresholdEditField = uieditfield(app.MyosortTab, 'numeric');
            app.MergeThresholdEditField.Limits = [0 1];
            app.MergeThresholdEditField.Value = 0.7;
            app.CutProportionEditFieldLabel = uilabel(app.MyosortTab);
            app.CutProportionEditFieldLabel.Text = 'Cut Proportion';
            app.TriageCutProportionEditField = uieditfield(app.MyosortTab, 'numeric');
            app.TriageCutProportionEditField.Limits = [0 1];
            app.TriageCutProportionEditField.Value = 0.2;
            app.TemplateDurationEditFieldLabel = uilabel(app.MyosortTab);
            app.TemplateDurationEditFieldLabel.Text = 'Template Duration';
            app.ClusterTemplateDurationEditField = uieditfield(app.MyosortTab, 'numeric');
            app.ClusterTemplateDurationEditField.Limits = [0 0.02];
            app.ClusterTemplateDurationEditField.Value = 0.01;
            app.WaveformDurationEditFieldLabel = uilabel(app.MyosortTab);
            app.WaveformDurationEditFieldLabel.Text = 'Waveform Duration';
            app.AlignWaveformDurationEditField = uieditfield(app.MyosortTab, 'numeric');
            app.AlignWaveformDurationEditField.Limits = [0 0.01];
            app.AlignWaveformDurationEditField.Value = 0.005;
            app.HighCutEditFieldLabel = uilabel(app.MyosortTab);
            app.HighCutEditFieldLabel.Text = 'High Cut';
            app.FilterHighCutEditField = uieditfield(app.MyosortTab, 'numeric');
            app.FilterHighCutEditField.Limits = [100 Inf];
            app.FilterHighCutEditField.RoundFractionalValues = 'on';
            app.FilterHighCutEditField.ValueDisplayFormat = '%i';
            app.FilterHighCutEditField.Value = Inf;
            app.LowCutEditFieldLabel = uilabel(app.MyosortTab);
            app.LowCutEditFieldLabel.Text = 'Low Cut';
            app.FilterLowCutEditField = uieditfield(app.MyosortTab, 'numeric');
            app.FilterLowCutEditField.Limits = [0 Inf];
            app.FilterLowCutEditField.RoundFractionalValues = 'on';
            app.FilterLowCutEditField.ValueDisplayFormat = '%i';
            app.FilterLowCutEditField.Value = 500;
            app.OrderEditFieldLabel = uilabel(app.MyosortTab);
            app.OrderEditFieldLabel.Text = 'Order';
            app.FilterOrderEditField = uieditfield(app.MyosortTab, 'numeric');
            app.FilterOrderEditField.Limits = [0 10];
            app.FilterOrderEditField.RoundFractionalValues = 'on';
            app.FilterOrderEditField.ValueDisplayFormat = '%i';
            app.FilterOrderEditField.Value = 2;
            app.MaxArrayEditFieldLabel = uilabel(app.MyosortTab);
            app.MaxArrayEditFieldLabel.Text = 'Max Array';
            app.MaxArrayEditField = uieditfield(app.MyosortTab, 'numeric');
            app.MaxArrayEditField.Limits = [0 Inf];
            app.MaxArrayEditField.RoundFractionalValues = 'on';
            app.MaxArrayEditField.Value = 1000000000;
            app.SampleRateEditFieldLabel = uilabel(app.MyosortTab);
            app.SampleRateEditFieldLabel.Text = 'Sample Rate';
            app.SampleRateEditField = uieditfield(app.MyosortTab, 'numeric');
            app.SavePathEditFieldLabel = uilabel(app.MyosortTab);
            app.SavePathEditFieldLabel.Text = 'Save Path';
            app.SavePathEditField = uieditfield(app.MyosortTab, 'text');
            app.SavePathEditField.ValueChangedFcn = createCallbackFcn(app, @SavePathEditFieldValueChanged, true);
            app.TimeseriesTab = uitab(app.TabGroup);
            app.TimeseriesTab.Title = 'Timeseries';
            app.TimeseriesAxes = uiaxes(app.TimeseriesTab);
            title(app.TimeseriesAxes, '')
            xlabel(app.TimeseriesAxes, 'time (s)')
            ylabel(app.TimeseriesAxes, '')
            app.TimeseriesSlider = uislider(app.TimeseriesTab);
            app.TimeseriesSlider.Limits = [0 1];
            app.TimeseriesSlider.MajorTicks = [0 1];
            app.TimeseriesSlider.MajorTickLabels = {''};
            app.TimeseriesSlider.ValueChangedFcn = createCallbackFcn(app, @TimeseriesSliderValueChanged, true);
            app.TimeseriesSlider.MinorTicks = [];
            app.TimeseriesChannelsListBox = uilistbox(app.TimeseriesTab);
            app.TimeseriesChannelsListBox.Items = {'Channel 1'};
            app.TimeseriesChannelsListBox.Multiselect = 'on';
            app.TimeseriesChannelsListBox.ValueChangedFcn = createCallbackFcn(app, @TimeseriesChannelsListBoxValueChanged, true);
            app.TimeseriesChannelsListBox.Value = {'Channel 1'};
            app.FrameLabel = uilabel(app.TimeseriesTab);
            app.FrameLabel.Text = 'Frame';
            app.TimeseriesFrameEditField = uieditfield(app.TimeseriesTab, 'numeric');
            app.TimeseriesFrameEditField.Limits = [0 300];
            app.TimeseriesFrameEditField.ValueChangedFcn = createCallbackFcn(app, @TimeseriesFrameEditFieldValueChanged, true);
            app.TimeseriesFrameEditField.Value = 30;
            app.TimeseriesFrameLabel = uilabel(app.TimeseriesTab);
            app.TimeseriesFrameLabel.Text = 's';
            app.RandomFramePushButton = uibutton(app.TimeseriesTab, 'push');
            app.RandomFramePushButton.ButtonPushedFcn = createCallbackFcn(app, @RandomFramePushButtonPushed, true);
            app.RandomFramePushButton.Text = 'Randomize';
            app.OverlayLabel = uilabel(app.TimeseriesTab);
            app.OverlayLabel.Text = 'Overlay';
            app.OverlayListBox = uilistbox(app.TimeseriesTab);
            app.OverlayListBox.Items = {'Detected Spikes', 'Aligned Spikes', 'Fit'};
            app.OverlayListBox.Multiselect = 'on';
            app.OverlayListBox.ValueChangedFcn = createCallbackFcn(app, @OverlayListBoxValueChanged, true);
            app.OverlayListBox.Value = {};
            app.TimeseriesBotmPushButton = uibutton(app.TimeseriesTab, 'push');
            app.TimeseriesBotmPushButton.ButtonPushedFcn = createCallbackFcn(app, @TimeseriesBotmPushButtonPushed, true);
            app.TimeseriesBotmPushButton.Text = 'BOTM';
            app.PositionLabel = uilabel(app.TimeseriesTab);
            app.PositionLabel.Text = 'Position';
            app.TimeseriesPositionEditField = uieditfield(app.TimeseriesTab, 'numeric');
            app.TimeseriesPositionEditField.Limits = [0 Inf];
            app.TimeseriesPositionEditField.ValueDisplayFormat = '%.3f';
            app.TimeseriesPositionEditField.ValueChangedFcn = createCallbackFcn(app, @TimeseriesPositionEditFieldValueChanged, true);
            app.TimeseriesPositionLabel = uilabel(app.TimeseriesTab);
            app.TimeseriesPositionLabel.Text = 's';
            app.TimeseriesAxesUnitsSwitch = uiswitch(app.TimeseriesTab, 'slider');
            app.TimeseriesAxesUnitsSwitch.Items = {'sigma', 'au'};
            app.TimeseriesAxesUnitsSwitch.ValueChangedFcn = createCallbackFcn(app, @TimeseriesAxesUnitsSwitchValueChanged, true);
            app.TimeseriesAxesUnitsSwitch.Value = 'au';
            app.TemplatesTab = uitab(app.TabGroup);
            app.TemplatesTab.Title = 'Templates';
            app.ActiveTemplatesAxes = uiaxes(app.TemplatesTab);
            title(app.ActiveTemplatesAxes, 'Active')
            xlabel(app.ActiveTemplatesAxes, 'X')
            ylabel(app.ActiveTemplatesAxes, 'Y')
            app.InactiveTemplatesAxes = uiaxes(app.TemplatesTab);
            title(app.InactiveTemplatesAxes, 'Inactive')
            xlabel(app.InactiveTemplatesAxes, 'X')
            ylabel(app.InactiveTemplatesAxes, 'Y')
            app.ActiveListBoxLabel = uilabel(app.TemplatesTab);
            app.ActiveListBoxLabel.Text = 'Active';
            app.ActiveTemplatesListBox = uilistbox(app.TemplatesTab);
            app.ActiveTemplatesListBox.Items = {'Unit 1'};
            app.ActiveTemplatesListBox.Multiselect = 'on';
            app.ActiveTemplatesListBox.ValueChangedFcn = createCallbackFcn(app, @ActiveTemplatesListBoxValueChanged, true);
            app.ActiveTemplatesListBox.Value = {'Unit 1'};
            app.InactiveListBoxLabel = uilabel(app.TemplatesTab);
            app.InactiveListBoxLabel.Text = 'Inactive';
            app.InactiveTemplatesListBox = uilistbox(app.TemplatesTab);
            app.InactiveTemplatesListBox.Items = {'Unit 1'};
            app.InactiveTemplatesListBox.Multiselect = 'on';
            app.InactiveTemplatesListBox.ValueChangedFcn = createCallbackFcn(app, @InactiveTemplatesListBoxValueChanged, true);
            app.InactiveTemplatesListBox.Value = {'Unit 1'};
            app.InactivateTemplatePushButton = uibutton(app.TemplatesTab, 'push');
            app.InactivateTemplatePushButton.ButtonPushedFcn = createCallbackFcn(app, @InactivateTemplatePushButtonPushed, true);
            app.InactivateTemplatePushButton.Text = 'Inactivate';
            app.ActivateTemplatePushButton = uibutton(app.TemplatesTab, 'push');
            app.ActivateTemplatePushButton.ButtonPushedFcn = createCallbackFcn(app, @ActivateTemplatePushButtonPushed, true);
            app.ActivateTemplatePushButton.Text = 'Activate';
            app.PlotSelectedActiveCheckBox = uicheckbox(app.TemplatesTab);
            app.PlotSelectedActiveCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotSelectedActiveCheckBoxValueChanged, true);
            app.PlotSelectedActiveCheckBox.Text = '';
            app.TemplatesBotmPushButton = uibutton(app.TemplatesTab, 'push');
            app.TemplatesBotmPushButton.ButtonPushedFcn = createCallbackFcn(app, @TemplatesBotmPushButtonPushed, true);
            app.TemplatesBotmPushButton.Text = 'BOTM';
            app.BatchDurationEditFieldLabel_2 = uilabel(app.TemplatesTab);
            app.BatchDurationEditFieldLabel_2.Text = 'Batch Duration';
            app.TemplatesBotmBatchDurationEditField = uieditfield(app.TemplatesTab, 'numeric');
            app.TemplatesBotmBatchDurationEditField.LowerLimitInclusive = 'off';
            app.TemplatesBotmBatchDurationEditField.Limits = [0 60];
            app.TemplatesBotmBatchDurationEditField.Value = 5;
            app.TemplatesBotmBatchDurationLabel = uilabel(app.TemplatesTab);
            app.TemplatesBotmBatchDurationLabel.Text = 's';
            app.BatchesLabel = uilabel(app.TemplatesTab);
            app.BatchesLabel.Text = 'Batches';
            app.TemplatesBotmBatchesEditField = uieditfield(app.TemplatesTab, 'numeric');
            app.TemplatesBotmBatchesEditField.Limits = [0 Inf];
            app.TemplatesBotmBatchesEditField.RoundFractionalValues = 'on';
            app.TemplatesBotmBatchesEditField.ValueDisplayFormat = '%i';
            app.TemplatesBotmBatchesEditField.Value = 1;
            app.ResetTemplatesPushButton = uibutton(app.TemplatesTab, 'push');
            app.ResetTemplatesPushButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTemplatesPushButtonPushed, true);
            app.ResetTemplatesPushButton.Text = 'Reset';
            app.PlotSelectedLabel = uilabel(app.TemplatesTab);
            app.PlotSelectedLabel.Text = 'Plot Selected';
            app.PlotSelectedInactiveCheckBox = uicheckbox(app.TemplatesTab);
            app.PlotSelectedInactiveCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotSelectedInactiveCheckBoxValueChanged, true);
            app.PlotSelectedInactiveCheckBox.Text = '';
            app.ShiftActiveTemplateEditField = uieditfield(app.TemplatesTab, 'numeric');
            app.ShiftActiveTemplateEditField.Limits = [-20 20];
            app.msLabel = uilabel(app.TemplatesTab);
            app.msLabel.Text = 'ms';
            app.ShiftActiveTemplatePushButton = uibutton(app.TemplatesTab, 'push');
            app.ShiftActiveTemplatePushButton.ButtonPushedFcn = createCallbackFcn(app, @ShiftActiveTemplatePushButtonPushed, true);
            app.ShiftActiveTemplatePushButton.Text = 'Shift';
            app.TemplatesSourceDropDown = uidropdown(app.TemplatesTab);
            app.TemplatesSourceDropDown.Items = {'Cluster'};
            app.TemplatesSourceDropDown.Value = 'Cluster';
            app.DeleteTemplatePushButton = uibutton(app.TemplatesTab, 'push');
            app.DeleteTemplatePushButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteTemplatePushButtonPushed, true);
            app.DeleteTemplatePushButton.Text = 'Delete';
            app.ToggleGoodTemplatePushButton = uibutton(app.TemplatesTab, 'push');
            app.ToggleGoodTemplatePushButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleGoodTemplatePushButtonPushed, true);
            app.ToggleGoodTemplatePushButton.Text = 'Good / Neutral';
            app.WaveformsTab = uitab(app.TabGroup);
            app.WaveformsTab.Title = 'Waveforms';
            app.WaveformAxes = uiaxes(app.WaveformsTab);
            title(app.WaveformAxes, 'Waveforms')
            xlabel(app.WaveformAxes, 'X')
            ylabel(app.WaveformAxes, 'Y')
            app.FeaturesAxes = uiaxes(app.WaveformsTab);
            title(app.FeaturesAxes, 'Features')
            xlabel(app.FeaturesAxes, 'X')
            ylabel(app.FeaturesAxes, 'Y')
            app.WaveformUnitsListBox = uilistbox(app.WaveformsTab);
            app.WaveformUnitsListBox.Items = {'Unit 1'};
            app.WaveformUnitsListBox.Multiselect = 'on';
            app.WaveformUnitsListBox.ValueChangedFcn = createCallbackFcn(app, @WaveformUnitsListBoxValueChanged, true);
            app.WaveformUnitsListBox.Value = {'Unit 1'};
            app.WaveformPlotStyleSwitch = uiswitch(app.WaveformsTab, 'slider');
            app.WaveformPlotStyleSwitch.Items = {'Hist', 'Line'};
            app.WaveformPlotStyleSwitch.ValueChangedFcn = createCallbackFcn(app, @WaveformPlotStyleSwitchValueChanged, true);
            app.WaveformPlotStyleSwitch.Value = 'Hist';
            app.MaxLinesLabel = uilabel(app.WaveformsTab);
            app.MaxLinesLabel.Text = 'Max Lines';
            app.WaveformMaxLinesEditField = uieditfield(app.WaveformsTab, 'numeric');
            app.WaveformMaxLinesEditField.Limits = [0 10000];
            app.WaveformMaxLinesEditField.RoundFractionalValues = 'on';
            app.WaveformMaxLinesEditField.ValueDisplayFormat = '%i';
            app.WaveformMaxLinesEditField.ValueChangedFcn = createCallbackFcn(app, @WaveformMaxLinesEditFieldValueChanged, true);
            app.WaveformMaxLinesEditField.Value = 1000;
            app.ResampleWaveformsPushButton = uibutton(app.WaveformsTab, 'push');
            app.ResampleWaveformsPushButton.ButtonPushedFcn = createCallbackFcn(app, @ResampleWaveformsPushButtonPushed, true);
            app.ResampleWaveformsPushButton.Text = 'Resample';
            app.CutProportionLabel = uilabel(app.WaveformsTab);
            app.CutProportionLabel.Text = 'Cut Proportion';
            app.WaveformsCutProportionEditField = uieditfield(app.WaveformsTab, 'numeric');
            app.WaveformsCutProportionEditField.Limits = [0 1];
            app.WaveformsCutProportionEditField.Value = 0.1;
            app.WaveformChannelsListBox = uilistbox(app.WaveformsTab);
            app.WaveformChannelsListBox.Items = {'Channel 1'};
            app.WaveformChannelsListBox.Multiselect = 'on';
            app.WaveformChannelsListBox.ValueChangedFcn = createCallbackFcn(app, @WaveformChannelsListBoxValueChanged, true);
            app.WaveformChannelsListBox.Value = {'Channel 1'};
            app.RemoveOutliersPushButton = uibutton(app.WaveformsTab, 'push');
            app.RemoveOutliersPushButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveOutliersPushButtonPushed, true);
            app.RemoveOutliersPushButton.Text = 'Remove Outliers';
            app.Feat1Label = uilabel(app.WaveformsTab);
            app.Feat1Label.Text = 'Feat. 1';
            app.Feature1DropDown = uidropdown(app.WaveformsTab);
            app.Feature1DropDown.Items = {'Time', 'PC1', 'PC2', 'PC3'};
            app.Feature1DropDown.ValueChangedFcn = createCallbackFcn(app, @Feature1DropDownValueChanged, true);
            app.Feature1DropDown.Value = 'Time';
            app.Feat2Label = uilabel(app.WaveformsTab);
            app.Feat2Label.Text = 'Feat. 2';
            app.Feature2DropDown = uidropdown(app.WaveformsTab);
            app.Feature2DropDown.Items = {'Time', 'PC1', 'PC2', 'PC3'};
            app.Feature2DropDown.ValueChangedFcn = createCallbackFcn(app, @Feature2DropDownValueChanged, true);
            app.Feature2DropDown.Value = 'PC1';
            app.Feat3Label = uilabel(app.WaveformsTab);
            app.Feat3Label.Text = 'Feat. 3';
            app.Feature3DropDown = uidropdown(app.WaveformsTab);
            app.Feature3DropDown.Items = {'None', 'Time', 'PC1', 'PC2', 'PC3'};
            app.Feature3DropDown.ValueChangedFcn = createCallbackFcn(app, @Feature3DropDownValueChanged, true);
            app.Feature3DropDown.Value = 'None';
            app.FeaturePolygonCheckBox = uicheckbox(app.WaveformsTab);
            app.FeaturePolygonCheckBox.ValueChangedFcn = createCallbackFcn(app, @FeaturePolygonCheckBoxValueChanged, true);
            app.FeaturePolygonCheckBox.Text = 'Poly';
            app.SplitWaveformsPushButton = uibutton(app.WaveformsTab, 'push');
            app.SplitWaveformsPushButton.ButtonPushedFcn = createCallbackFcn(app, @SplitWaveformsPushButtonPushed, true);
            app.SplitWaveformsPushButton.Text = 'Split';
            app.OverlayWaveformsCheckBox = uicheckbox(app.WaveformsTab);
            app.OverlayWaveformsCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlayWaveformsCheckBoxValueChanged, true);
            app.OverlayWaveformsCheckBox.Text = 'Overlay';
            app.MergeWaveformsPushButton = uibutton(app.WaveformsTab, 'push');
            app.MergeWaveformsPushButton.ButtonPushedFcn = createCallbackFcn(app, @MergeWaveformsPushButtonPushed, true);
            app.MergeWaveformsPushButton.Text = 'Merge';
            app.ClearPolygonPushButton = uibutton(app.WaveformsTab, 'push');
            app.ClearPolygonPushButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPolygonPushButtonPushed, true);
            app.ClearPolygonPushButton.Text = 'Clear';
            app.SetPolygonToggleButton = uibutton(app.WaveformsTab, 'state');
            app.SetPolygonToggleButton.ValueChangedFcn = createCallbackFcn(app, @SetPolygonToggleButtonValueChanged, true);
            app.SetPolygonToggleButton.Text = 'Set';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = templatemanager

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end