classdef templatemanager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ShapesTab                      matlab.ui.container.Tab
        ShapesAxes                     matlab.ui.control.UIAxes
        ShapesListBox                  matlab.ui.control.ListBox
        SourceShapesButtonGroup        matlab.ui.container.ButtonGroup
        TemplatesRadioButton           matlab.ui.control.RadioButton
        MatchedRadioButton             matlab.ui.control.RadioButton
        UnmatchedRadioButton           matlab.ui.control.RadioButton
        ExiledRadioButton              matlab.ui.control.RadioButton
        ShapesPlotStyleSwitch          matlab.ui.control.Switch
        Label                          matlab.ui.control.Label
        OutlierPercentEditField        matlab.ui.control.NumericEditField
        RemoveOutliersPushButton       matlab.ui.control.Button
        MatchedTab                     matlab.ui.container.Tab
        MatchedAxes                    matlab.ui.control.UIAxes
        MatchedListBox                 matlab.ui.control.ListBox
        AddMatchedPushButton           matlab.ui.control.Button
        ExileMatchedPushButton         matlab.ui.control.Button
        UnmatchedTab                   matlab.ui.container.Tab
        UnmatchedAxes                  matlab.ui.control.UIAxes
        UnmatchedListBox               matlab.ui.control.ListBox
        AddUnmatchedPushButton         matlab.ui.control.Button
        ExileUnmatchedPushButton       matlab.ui.control.Button
        ExiledTab                      matlab.ui.container.Tab
        ExiledAxes                     matlab.ui.control.UIAxes
        ExiledListBox                  matlab.ui.control.ListBox
        RestoreExiledPushButton        matlab.ui.control.Button
        TemplatesAxes                  matlab.ui.control.UIAxes
        TemplatesListBox               matlab.ui.control.ListBox
        RemoveTemplatePushButton       matlab.ui.control.Button
        SimilarityThresholdEditFieldLabel  matlab.ui.control.Label
        SimilarityThresholdEditField   matlab.ui.control.NumericEditField
        PlotSelectedTemplatesCheckBox  matlab.ui.control.CheckBox
        TemplatesLabel                 matlab.ui.control.Label
        rangesEditFieldLabel           matlab.ui.control.Label
        RangeEditField                 matlab.ui.control.EditField
        RunBotmPushButton              matlab.ui.control.Button
        HoldBotmFiguresCheckBox        matlab.ui.control.CheckBox
        ExportPushButton               matlab.ui.control.Button
        ExileTemplatePushButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        X           % timeseries data
        Fs          % sample rate (Hz)
        t           % time vector
        Cinv        % inverse covariance matrix
        shapes      % waveform shapes
        muapw       % MUAPW
        label       % shape labels (for determining outliers)
        outlierCut  % percentage of outliers removed
        waveLen     % wavelength (samples)
        nChan       % number of channels
        nUnit       % number of units
        wSim        % waveform similarity
        wEnt        % waveform entropy
        assign      % one-hot assignment (good, duplicate, solo)
        figNo       % figure numbers (for BOTM plotting)
    end
    
    methods (Access = private)
        
        function reset_assignments(app)
            grp = findpaths(app.wSim > app.SimilarityThresholdEditField.Value);
            [isGood,isMatched] = deal(false(app.nUnit,1));
            for ii = 1:length(grp)
                [~,bestUnitIdx] = min(mean(app.wEnt(grp{ii},:),2));
                isMatched(setdiff(grp{ii},grp{ii}(bestUnitIdx))) = true;
                isGood(grp{ii}(bestUnitIdx)) = true;
            end
            app.assign = cat(3, [isGood, isMatched, ~(isGood|isMatched), false(app.nUnit,1)], [false(app.nUnit,1), (isGood|isMatched), ~(isGood|isMatched), false(app.nUnit,1)]);
            
            % update listboxes
            update_listboxes(app);
            
            % update plots
            update_templates_plot(app)
            update_shapes_plot(app)
            update_matched_plot(app)
            update_unmatched_plot(app)
        end
        
        function update_listboxes(app)
            % good templates
            app.TemplatesListBox.Items = cellfun(@(idx,n) sprintf('%02i. Unit %i',idx,n),num2cell((1:nnz(app.assign(:,1,1)))'),num2cell(find(app.assign(:,1,1))),'uni',false);
            % matched templates
            app.MatchedListBox.Items   = cellfun(@(n) sprintf('Unit %i',n),num2cell(find(app.assign(:,2,1))),'uni',false);
            % unmatched templates
            app.UnmatchedListBox.Items = cellfun(@(n) sprintf('Unit %i',n),num2cell(find(app.assign(:,3,1))),'uni',false);
            % exiled templates
            app.ExiledListBox.Items    = cellfun(@(n) sprintf('Unit %i',n),num2cell(find(app.assign(:,4,1))),'uni',false);
            % shapes
            update_shapes_listbox(app)
        end
        
        function update_shapes_listbox(app)
            switch app.SourceShapesButtonGroup.SelectedObject.Text
                case 'Templates'
                    selectedIdx = 1;
                case 'Matched'
                    selectedIdx = 2;
                case 'Unmatched'
                    selectedIdx = 3;
                case 'Exiled'
                    selectedIdx = 4;
            end
            app.ShapesListBox.Items = cellfun(@(n) sprintf('Unit %i',n),num2cell(find(app.assign(:,selectedIdx,1))),'uni',false);
        end
        
        function update_templates_plot(app)
            goodUnit = find(app.assign(:,1,1));
            cmap = brewermap(length(goodUnit),'Spectral');
            cmap = min([1 1 1],max([0 0 0],cmap-0.2));
            if app.PlotSelectedTemplatesCheckBox.Value
                selectedUnit = ismember(app.TemplatesListBox.Items,app.TemplatesListBox.Value);
                goodUnit = goodUnit(selectedUnit);
            else
                selectedUnit = true(size(goodUnit));
            end
            if isempty(goodUnit)
                cla(app.TemplatesAxes,'reset')
                return
            end
            xTickLabel = cellfun(@(n) sprintf('%i',n), num2cell(goodUnit(:)), 'uni',false);
            plotwavetemplate(app.muapw(:,:,goodUnit),'axes',app.TemplatesAxes,'cmap',cmap(selectedUnit,:),'xTickLabel',xTickLabel,'chanLabels','off')
        end
        
        function update_matched_plot(app)
            matchedUnit = find(app.assign(:,2,1));
            cmap = brewermap(length(matchedUnit),'Spectral');
            cmap = min([1 1 1],max([0 0 0],cmap-0.2));
            selectedUnit = ismember(app.MatchedListBox.Items,app.MatchedListBox.Value);
            matchedUnit = matchedUnit(selectedUnit);
            if isempty(matchedUnit)
                cla(app.MatchedAxes,'reset')
                return
            end
            plotwavetemplate(app.muapw(:,:,matchedUnit),'axes',app.MatchedAxes,'cmap',cmap(selectedUnit,:),'xTickLabel',cellfun(@num2str,num2cell(matchedUnit),'uni',false),'chanLabels','off')
        end
        
        function update_unmatched_plot(app)
            unmatchedUnit = find(app.assign(:,3,1));
            cmap = brewermap(length(unmatchedUnit),'Spectral');
            cmap = min([1 1 1],max([0 0 0],cmap-0.2));
            selectedUnit = ismember(app.UnmatchedListBox.Items,app.UnmatchedListBox.Value);
            unmatchedUnit = unmatchedUnit(selectedUnit);
            if isempty(unmatchedUnit)
                cla(app.UnmatchedAxes,'reset')
                return
            end
            plotwavetemplate(app.muapw(:,:,unmatchedUnit),'axes',app.UnmatchedAxes,'cmap',cmap(selectedUnit,:),'xTickLabel',cellfun(@num2str,num2cell(unmatchedUnit),'uni',false),'chanLabels','off')
        end
        
        function update_exiled_plot(app)
            exiledUnit = find(app.assign(:,4,1));
            cmap = brewermap(length(exiledUnit),'Spectral');
            cmap = min([1 1 1],max([0 0 0],cmap-0.2));
            selectedUnit = ismember(app.ExiledListBox.Items,app.ExiledListBox.Value);
            exiledUnit = exiledUnit(selectedUnit);
            if isempty(exiledUnit)
                cla(app.ExiledAxes,'reset')
                return
            end
            plotwavetemplate(app.muapw(:,:,exiledUnit),'axes',app.ExiledAxes,'cmap',cmap(selectedUnit,:),'xTickLabel',cellfun(@num2str,num2cell(exiledUnit),'uni',false),'chanLabels','off')
        end
        
        function update_shapes_plot(app)
            switch app.SourceShapesButtonGroup.SelectedObject.Text
                case 'Templates'
                    selectedIdx = 1;
                case 'Matched'
                    selectedIdx = 2;
                case 'Unmatched'
                    selectedIdx = 3;
                case 'Exiled'
                    selectedIdx = 4;
            end
            
            unitGroup = find(app.assign(:,selectedIdx,1));
            selectedUnit = ismember(app.ShapesListBox.Items,app.ShapesListBox.Value);
            goodUnit = unitGroup(selectedUnit);
            
            if isempty(goodUnit)
                cla(app.ShapesAxes,'reset')
                return
            end
            
            app.OutlierPercentEditField.Value = app.outlierCut(goodUnit);
            
            ws = cellfun(@(w,l) w(l==1,:), app.shapes(goodUnit,:), repmat(app.label(goodUnit),1,app.nChan), 'uni', false);
            
            if all(cellfun(@isempty,ws))
                cla(app.ShapesAxes,'reset')
                return
            end
            
            if strcmp(app.ShapesPlotStyleSwitch.Value, 'Hist')
                
                histfun(ws,'plot',true,'axes',app.ShapesAxes,'nBins',5e3,'count',size(ws{1},1));
                
            else % Line
                
                Y_PAD = 0;
                Y_TICK = 1/2;
                
                % normalize waveforms
                yAbsLim = cellfun(@(x) max(max(abs(x),[],2),[],1),ws);
                ws = cellfun(@(x,a) x/a, ws, num2cell(yAbsLim),'uni',false);
                
                % apply vertical offset to each channel
                yPos = flipud([0;cumsum((1+Y_PAD)*2*ones(app.nChan-1,1))]);
                ws = cellfun(@(x,b) x+b, ws(:),num2cell(yPos(:)),'uni',false);
                
                % y-tick position and values
                yTickVal = Y_TICK;
                yTickPos = [yPos-yTickVal, yPos, yPos+fliplr(yTickVal)];
                yTick = round((yTickPos - yPos).*yAbsLim(:));
                
                yTickPos = reshape(fliplr(yTickPos)',(1+2*length(yTickVal))*app.nChan,1);
                yTick = reshape(fliplr(yTick)',(1+2*length(yTickVal))*app.nChan,1);
                
                yTickPos = flipud(yTickPos);
                yTickLabel = cellfun(@num2str,flipud(num2cell(yTick)),'uni',false);
                
                % plot
                cla(app.ShapesAxes,'reset')
                hold(app.ShapesAxes,'on')
                for ii = 1:length(ws)
                    plot(app.ShapesAxes,1:app.waveLen,ws{ii}','k')
                end
                
                set(app.ShapesAxes,'yTick',yTickPos)
                set(app.ShapesAxes,'yTickLabel',yTickLabel)
                
                yl = [-1 1+2*(app.nChan-1)];
                set(app.ShapesAxes,'yLim',yl)
                text(app.ShapesAxes,0.05*app.waveLen,0.975*yl(2),sprintf('%i',size(ws{1},1)),'fontsize',15)
            end
        end
        
        function components = get_interactive(app)
            components = fieldnames(app);
            components = components(cellfun(@(x) contains(x,{'CheckBox','PushButton','RadioButton','ListBox','Edit'}), components));
        end
        
        function disable_interactive(app)
            comp = get_interactive(app);
            for ii = 1:length(comp)
                app.(comp{ii}).Enable = 'off';
            end
        end
        
        function enable_interactive(app)
            comp = get_interactive(app);
            for ii = 1:length(comp)
                app.(comp{ii}).Enable = 'on';
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, X, Fs, Cinv, waveforms)
            app.X = X;
            app.Fs = Fs;
            app.t = (1:size(X,1))/Fs;
            app.Cinv = Cinv;
            
            [app.muapw,sortIdx] = wavetemplate(waveforms,'sort',true);
            [app.waveLen,app.nChan,app.nUnit] = size(app.muapw);
            app.shapes = waveforms(sortIdx,:);
            
            app.outlierCut = zeros(app.nUnit,1);
            app.label = cellfun(@(x) ones(size(x,1),1), app.shapes(:,1), 'uni', false);
            
            [wXC,enerRat] = wavexcorr(app.muapw);
            app.wSim = wXC.*enerRat;
            
            app.wEnt = waveclusent(app.shapes,3);
            
            app.figNo = [];
            
            reset_assignments(app);
        end

        % Value changed function: TemplatesListBox
        function TemplatesListBoxValueChanged(app, event)
            if app.PlotSelectedTemplatesCheckBox.Value
                update_templates_plot(app)
            end
        end

        % Value changed function: MatchedListBox
        function MatchedListBoxValueChanged(app, event)
            update_matched_plot(app)
        end

        % Value changed function: UnmatchedListBox
        function UnmatchedListBoxValueChanged(app, event)
            update_unmatched_plot(app)
        end

        % Value changed function: PlotSelectedTemplatesCheckBox
        function PlotSelectedTemplatesCheckBoxValueChanged(app, event)
            update_templates_plot(app)
        end

        % Button pushed function: RemoveTemplatePushButton
        function RemoveTemplatePushButtonPushed(app, event)
            goodUnit = find(app.assign(:,1,1));
            rmvUnit = goodUnit(ismember(app.TemplatesListBox.Items,app.TemplatesListBox.Value));
            app.assign(rmvUnit,:,1) = app.assign(rmvUnit,:,2);
            
            update_listboxes(app)
            
            update_templates_plot(app)
            if any(app.assign(rmvUnit,2,2))
                update_matched_plot(app)
            end
            if any(app.assign(rmvUnit,3,2))
                update_unmatched_plot(app)
            end
        end

        % Button pushed function: AddMatchedPushButton
        function AddMatchedPushButtonPushed(app, event)
            matchedUnit = find(app.assign(:,2,1));
            addUnit = matchedUnit(ismember(app.MatchedListBox.Items,app.MatchedListBox.Value));
            app.assign(addUnit,2,1) = false;
            app.assign(addUnit,1,1) = true;
            
            update_listboxes(app)
            
            update_templates_plot(app)
            update_matched_plot(app)
        end

        % Button pushed function: AddUnmatchedPushButton
        function AddUnmatchedPushButtonPushed(app, event)
            unmatchedUnit = find(app.assign(:,3,1));
            addUnit = unmatchedUnit(ismember(app.UnmatchedListBox.Items,app.UnmatchedListBox.Value));
            app.assign(addUnit,3,1) = false;
            app.assign(addUnit,1,1) = true;
            
            update_listboxes(app)
            
            update_templates_plot(app)
            update_unmatched_plot(app)
        end

        % Value changed function: SimilarityThresholdEditField
        function SimilarityThresholdEditFieldValueChanged(app, event)
            reset_assignments(app)
        end

        % Value changed function: ShapesListBox
        function ShapesListBoxValueChanged(app, event)
            update_shapes_plot(app)
        end

        % Value changed function: RangeEditField
        function RangeEditFieldValueChanged(app, event)
            value = str2num(app.RangeEditField.Value);
            if isempty(value) || length(value)~=2 || value(2)<=value(1)
                app.RangeEditField.Value = '[0 60]';
            end
        end

        % Button pushed function: RunBotmPushButton
        function RunBotmPushButtonPushed(app, event)
            disable_interactive(app)
            pause(0.1)
            if isempty(app.figNo) || ~app.HoldBotmFiguresCheckBox.Value
                openFigs = findobj('Type', 'figure');
                if isempty(openFigs)
                    app.figNo = 1:4;
                else
                    app.figNo = (1:4) + max(arrayfun(@(x) x.Number,openFigs));
                end
            end
            tLim = str2num(app.RangeEditField.Value);
            botm(app.X(app.t>=tLim(1) & app.t<=tLim(2),:),app.Fs,app.muapw(:,:,app.assign(:,1,1)),app.Cinv,'plot',{'fit','res','resen','rec'},'figNo',app.figNo);
            enable_interactive(app)
        end

        % Button pushed function: ExportPushButton
        function ExportPushButtonPushed(app, event)
            [fileName,pathName] = uiputfile();
            templates = app.muapw;
            w = templates(:,:,app.assign(:,1,1));
            UnitAssign = struct('good',find(app.assign(:,1,1)),'duplicate',find(app.assign(:,2,1)),'solo',find(app.assign(:,3,1)));
            save([pathName,fileName],'w','templates','UnitAssign')
        end

        % Button pushed function: ExileTemplatePushButton
        function ExileTemplatePushButtonPushed(app, event)
            goodUnit = find(app.assign(:,1,1));
            exileUnit = goodUnit(ismember(app.TemplatesListBox.Items,app.TemplatesListBox.Value));
            app.assign(exileUnit,:,1) = repmat([false(1,3),true],length(exileUnit),1);
            
            update_listboxes(app)
            
            update_templates_plot(app)
            update_exiled_plot(app)
        end

        % Button pushed function: ExileMatchedPushButton
        function ExileMatchedPushButtonPushed(app, event)
            matchedUnit = find(app.assign(:,2,1));
            exileUnit = matchedUnit(ismember(app.MatchedListBox.Items,app.MatchedListBox.Value));
            app.assign(exileUnit,:,1) = repmat([false(1,3),true],length(exileUnit),1);
            
            update_listboxes(app)
            
            update_templates_plot(app)
            update_matched_plot(app)
            update_exiled_plot(app)
        end

        % Button pushed function: ExileUnmatchedPushButton
        function ExileUnmatchedPushButtonPushed(app, event)
            unmatchedUnit = find(app.assign(:,3,1));
            exileUnit = unmatchedUnit(ismember(app.UnmatchedListBox.Items,app.UnmatchedListBox.Value));
            app.assign(exileUnit,:,1) = repmat([false(1,3),true],length(exileUnit),1);
            
            update_listboxes(app)
            
            update_templates_plot(app)
            update_unmatched_plot(app)
            update_exiled_plot(app)
        end

        % Button pushed function: RestoreExiledPushButton
        function RestoreExiledPushButtonPushed(app, event)
            exiledUnit = find(app.assign(:,4,1));
            restUnit = exiledUnit(ismember(app.ExiledListBox.Items,app.ExiledListBox.Value));
            app.assign(restUnit,:,1) = app.assign(restUnit,:,2);
            
            update_listboxes(app)
            
            update_templates_plot(app)
            update_matched_plot(app)
            update_unmatched_plot(app)
            update_exiled_plot(app)
        end

        % Value changed function: ExiledListBox
        function ExiledListBoxValueChanged(app, event)
            update_exiled_plot(app)
        end

        % Selection changed function: SourceShapesButtonGroup
        function SourceShapesButtonGroupSelectionChanged(app, event)
            update_shapes_listbox(app)
            update_shapes_plot(app)
        end

        % Value changed function: ShapesPlotStyleSwitch
        function ShapesPlotStyleSwitchValueChanged(app, event)
            update_shapes_plot(app)
        end

        % Button pushed function: RemoveOutliersPushButton
        function RemoveOutliersPushButtonPushed(app, event)
            disable_interactive(app)
            pause(0.1)
            
            switch app.SourceShapesButtonGroup.SelectedObject.Text
                case 'Templates'
                    selectedIdx = 1;
                case 'Matched'
                    selectedIdx = 2;
                case 'Unmatched'
                    selectedIdx = 3;
                case 'Exiled'
                    selectedIdx = 4;
            end
            
            unitGroup = find(app.assign(:,selectedIdx,1));
            selectedUnit = unitGroup(ismember(app.ShapesListBox.Items,app.ShapesListBox.Value));
            
            app.outlierCut(selectedUnit) = app.OutlierPercentEditField.Value;
            
            n = size(app.shapes{selectedUnit,1},1);
            if app.OutlierPercentEditField.Value == 0
                app.label{selectedUnit} = ones(n,1);
            else
                app.label{selectedUnit} = rmvoutliers(cell2mat(app.shapes(selectedUnit,:)), ones(n,1), 'cut', (100-app.OutlierPercentEditField.Value)/100);
            end
            
            if nnz(app.label{selectedUnit}==1) > 0
                app.muapw(:,:,selectedUnit) = cell2mat(cellfun(@(w,l) mean(w(l==1,:),1)', app.shapes(selectedUnit,:), repmat(app.label(selectedUnit),1,app.nChan), 'uni', false));
            else
                app.muapw(:,:,selectedUnit) = zeros(app.waveLen,app.nChan);
            end
            
            update_templates_plot(app)
            update_shapes_plot(app)
            update_matched_plot(app)
            update_unmatched_plot(app)
            enable_interactive(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ShapesTab = uitab(app.TabGroup);
            app.ShapesTab.Title = 'Waveform Shapes';
            app.ShapesAxes = uiaxes(app.ShapesTab);
            app.ShapesListBox = uilistbox(app.ShapesTab);
            app.ShapesListBox.Items = {'Unit 1', 'Unit 2'};
            app.ShapesListBox.ValueChangedFcn = createCallbackFcn(app, @ShapesListBoxValueChanged, true);
            app.ShapesListBox.Value = 'Unit 1';
            app.SourceShapesButtonGroup = uibuttongroup(app.ShapesTab);
            app.SourceShapesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SourceShapesButtonGroupSelectionChanged, true);
            app.SourceShapesButtonGroup.Title = 'Source';
            app.TemplatesRadioButton = uiradiobutton(app.SourceShapesButtonGroup);
            app.TemplatesRadioButton.Text = 'Templates';
            app.TemplatesRadioButton.Value = true;
            app.MatchedRadioButton = uiradiobutton(app.SourceShapesButtonGroup);
            app.MatchedRadioButton.Text = 'Matched';
            app.UnmatchedRadioButton = uiradiobutton(app.SourceShapesButtonGroup);
            app.UnmatchedRadioButton.Text = 'Unmatched';
            app.ExiledRadioButton = uiradiobutton(app.SourceShapesButtonGroup);
            app.ExiledRadioButton.Text = 'Exiled';
            app.ShapesPlotStyleSwitch = uiswitch(app.ShapesTab, 'slider');
            app.ShapesPlotStyleSwitch.Items = {'Hist', 'Line'};
            app.ShapesPlotStyleSwitch.ValueChangedFcn = createCallbackFcn(app, @ShapesPlotStyleSwitchValueChanged, true);
            app.ShapesPlotStyleSwitch.Value = 'Hist';
            app.Label = uilabel(app.ShapesTab);
            app.Label.Text = '%';
            app.OutlierPercentEditField = uieditfield(app.ShapesTab, 'numeric');
            app.OutlierPercentEditField.Limits = [0 100];
            app.RemoveOutliersPushButton = uibutton(app.ShapesTab, 'push');
            app.RemoveOutliersPushButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveOutliersPushButtonPushed, true);
            app.RemoveOutliersPushButton.Text = 'Remove Outliers';
            app.MatchedTab = uitab(app.TabGroup);
            app.MatchedTab.Title = 'Matched';
            app.MatchedAxes = uiaxes(app.MatchedTab);
            app.MatchedListBox = uilistbox(app.MatchedTab);
            app.MatchedListBox.Items = {'Unit 1', 'Unit 2'};
            app.MatchedListBox.Multiselect = 'on';
            app.MatchedListBox.ValueChangedFcn = createCallbackFcn(app, @MatchedListBoxValueChanged, true);
            app.MatchedListBox.Value = {'Unit 1'};
            app.AddMatchedPushButton = uibutton(app.MatchedTab, 'push');
            app.AddMatchedPushButton.ButtonPushedFcn = createCallbackFcn(app, @AddMatchedPushButtonPushed, true);
            app.AddMatchedPushButton.Text = 'Add';
            app.ExileMatchedPushButton = uibutton(app.MatchedTab, 'push');
            app.ExileMatchedPushButton.ButtonPushedFcn = createCallbackFcn(app, @ExileMatchedPushButtonPushed, true);
            app.ExileMatchedPushButton.Text = 'Exile';
            app.UnmatchedTab = uitab(app.TabGroup);
            app.UnmatchedTab.Title = 'Unmatched';
            app.UnmatchedAxes = uiaxes(app.UnmatchedTab);
            app.UnmatchedListBox = uilistbox(app.UnmatchedTab);
            app.UnmatchedListBox.Items = {'Unit 1', 'Unit 2'};
            app.UnmatchedListBox.Multiselect = 'on';
            app.UnmatchedListBox.ValueChangedFcn = createCallbackFcn(app, @UnmatchedListBoxValueChanged, true);
            app.UnmatchedListBox.Value = {'Unit 1'};
            app.AddUnmatchedPushButton = uibutton(app.UnmatchedTab, 'push');
            app.AddUnmatchedPushButton.ButtonPushedFcn = createCallbackFcn(app, @AddUnmatchedPushButtonPushed, true);
            app.AddUnmatchedPushButton.Text = 'Add';
            app.ExileUnmatchedPushButton = uibutton(app.UnmatchedTab, 'push');
            app.ExileUnmatchedPushButton.ButtonPushedFcn = createCallbackFcn(app, @ExileUnmatchedPushButtonPushed, true);
            app.ExileUnmatchedPushButton.Text = 'Exile';
            app.ExiledTab = uitab(app.TabGroup);
            app.ExiledTab.Title = 'Exiled';
            app.ExiledAxes = uiaxes(app.ExiledTab);
            app.ExiledListBox = uilistbox(app.ExiledTab);
            app.ExiledListBox.Items = {'Unit 1', 'Unit 2'};
            app.ExiledListBox.Multiselect = 'on';
            app.ExiledListBox.ValueChangedFcn = createCallbackFcn(app, @ExiledListBoxValueChanged, true);
            app.ExiledListBox.Value = {'Unit 1'};
            app.RestoreExiledPushButton = uibutton(app.ExiledTab, 'push');
            app.RestoreExiledPushButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreExiledPushButtonPushed, true);
            app.RestoreExiledPushButton.Text = 'Restore';
            app.TemplatesAxes = uiaxes(app.UIFigure);
            app.TemplatesListBox = uilistbox(app.UIFigure);
            app.TemplatesListBox.Items = {'Unit 1', 'Unit 2'};
            app.TemplatesListBox.Multiselect = 'on';
            app.TemplatesListBox.ValueChangedFcn = createCallbackFcn(app, @TemplatesListBoxValueChanged, true);
            app.TemplatesListBox.Value = {'Unit 1'};
            app.RemoveTemplatePushButton = uibutton(app.UIFigure, 'push');
            app.RemoveTemplatePushButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveTemplatePushButtonPushed, true);
            app.RemoveTemplatePushButton.Text = 'Remove';
            app.SimilarityThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.SimilarityThresholdEditFieldLabel.Text = {'Similarity'; 'Threshold'};
            app.SimilarityThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.SimilarityThresholdEditField.Limits = [-1 1];
            app.SimilarityThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @SimilarityThresholdEditFieldValueChanged, true);
            app.SimilarityThresholdEditField.Value = 0.9;
            app.PlotSelectedTemplatesCheckBox = uicheckbox(app.UIFigure);
            app.PlotSelectedTemplatesCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotSelectedTemplatesCheckBoxValueChanged, true);
            app.PlotSelectedTemplatesCheckBox.Text = {'Plot '; 'Selected'};
            app.TemplatesLabel = uilabel(app.UIFigure);
            app.TemplatesLabel.Text = 'Templates';
            app.rangesEditFieldLabel = uilabel(app.UIFigure);
            app.rangesEditFieldLabel.Text = 'range (s)';
            app.RangeEditField = uieditfield(app.UIFigure, 'text');
            app.RangeEditField.ValueChangedFcn = createCallbackFcn(app, @RangeEditFieldValueChanged, true);
            app.RangeEditField.Value = '[0 60]';
            app.RunBotmPushButton = uibutton(app.UIFigure, 'push');
            app.RunBotmPushButton.ButtonPushedFcn = createCallbackFcn(app, @RunBotmPushButtonPushed, true);
            app.RunBotmPushButton.Text = 'Run BOTM';
            app.HoldBotmFiguresCheckBox = uicheckbox(app.UIFigure);
            app.HoldBotmFiguresCheckBox.Text = 'Hold Figs';
            app.ExportPushButton = uibutton(app.UIFigure, 'push');
            app.ExportPushButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPushButtonPushed, true);
            app.ExportPushButton.Text = 'Export';
            app.ExileTemplatePushButton = uibutton(app.UIFigure, 'push');
            app.ExileTemplatePushButton.ButtonPushedFcn = createCallbackFcn(app, @ExileTemplatePushButtonPushed, true);
            app.ExileTemplatePushButton.Text = 'Exile';
        end
    end

    methods (Access = public)

        % Construct app
        function app = templatemanager(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end